classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Main                            matlab.ui.container.Tab
        LabelGlobalBioIm                matlab.ui.control.Label
        ButtonSelectPipeline            matlab.ui.control.Button
        DropDownPipeline                matlab.ui.control.DropDown
        NameLabel                       matlab.ui.control.Label
        EditFieldPipeline               matlab.ui.control.EditField
        DescriptionLabel                matlab.ui.control.Label
        TextAreaDescriptionPipeline     matlab.ui.control.TextArea
        LabelCopyright                  matlab.ui.control.Label
        ReferencesPleaseLabel           matlab.ui.control.Label
        ButtonCreatePipeline            matlab.ui.control.Button
        ButtonLoadPipeline              matlab.ui.control.Button
        LabelSelectaDefaultPipeline     matlab.ui.control.Label
        ForwardModel                    matlab.ui.container.Tab
        TextAreaForwardModel            matlab.ui.control.TextArea
        LabelTextArea                   matlab.ui.control.Label
        ButtonSelectLinOp               matlab.ui.control.Button
        ButtonSeeDocLinOp               matlab.ui.control.Button
        OperatorLabel                   matlab.ui.control.Label
        DropDownLinOp                   matlab.ui.control.DropDown
        PanelHierarchyLinOp             matlab.ui.container.Panel
        NotationLinOp1                  matlab.ui.control.Label
        NameLinOp1                      matlab.ui.control.Label
        ButtonSetParamsLinOp1           matlab.ui.control.Button
        ButtonUpLinOp1                  matlab.ui.control.Button
        ButtonDownLinOp1                matlab.ui.control.Button
        ButtonDeleteLinOp1              matlab.ui.control.Button
        NotationLinOp2                  matlab.ui.control.Label
        NameLinOp2                      matlab.ui.control.Label
        ButtonSetParamsLinOp2           matlab.ui.control.Button
        ButtonUpLinOp2                  matlab.ui.control.Button
        ButtonDownLinOp2                matlab.ui.control.Button
        ButtonDeleteLinOp2              matlab.ui.control.Button
        NotationLinOp3                  matlab.ui.control.Label
        NameLinOp3                      matlab.ui.control.Label
        ButtonSetParamsLinOp3           matlab.ui.control.Button
        ButtonUpLinOp3                  matlab.ui.control.Button
        ButtonDownLinOp3                matlab.ui.control.Button
        ButtonDeleteLinOp3              matlab.ui.control.Button
        NotationLinOp4                  matlab.ui.control.Label
        NameLinOp4                      matlab.ui.control.Label
        ButtonSetParamsLinOp4           matlab.ui.control.Button
        ButtonUpLinOp4                  matlab.ui.control.Button
        ButtonDownLinOp4                matlab.ui.control.Button
        ButtonDeleteLinOp4              matlab.ui.control.Button
        NotationLinOp5                  matlab.ui.control.Label
        NameLinOp5                      matlab.ui.control.Label
        ButtonSetParamsLinOp5           matlab.ui.control.Button
        ButtonUpLinOp5                  matlab.ui.control.Button
        ButtonDownLinOp5                matlab.ui.control.Button
        ButtonDeleteLinOp5              matlab.ui.control.Button
        NotationLinOp6                  matlab.ui.control.Label
        NameLinOp6                      matlab.ui.control.Label
        ButtonSetParamsLinOp6           matlab.ui.control.Button
        ButtonUpLinOp6                  matlab.ui.control.Button
        ButtonDownLinOp6                matlab.ui.control.Button
        ButtonDeleteLinOp6              matlab.ui.control.Button
        ButtonInfoLinOp                 matlab.ui.control.Button
        ButtonCheckPipelineFwdPanel     matlab.ui.control.Button
        ButtonSaveFwdPanel              matlab.ui.control.Button
        CostFunction                    matlab.ui.container.Tab
        TextAreaCost                    matlab.ui.control.TextArea
        CostFunctionLabel               matlab.ui.control.Label
        ButtonSelectDF                  matlab.ui.control.Button
        ButtonSeeDocDF                  matlab.ui.control.Button
        DatafidelityLabel               matlab.ui.control.Label
        DropDownDF                      matlab.ui.control.DropDown
        FMPanel_CostFunction            matlab.ui.container.Panel
        NotationRegul1                  matlab.ui.control.Label
        NameRegul1                      matlab.ui.control.Label
        ButtonSetParamsRegul1           matlab.ui.control.Button
        ButtonDeleteRegul1              matlab.ui.control.Button
        NotationRegul2                  matlab.ui.control.Label
        NameRegul2                      matlab.ui.control.Label
        ButtonSetParamsRegul2           matlab.ui.control.Button
        ButtonDeleteRegul2              matlab.ui.control.Button
        NotationRegul3                  matlab.ui.control.Label
        NameRegul3                      matlab.ui.control.Label
        ButtonSetParamsRegul3           matlab.ui.control.Button
        ButtonDeleteRegul3              matlab.ui.control.Button
        ButtonSelectRegul               matlab.ui.control.Button
        ButtonSeeDocRegul               matlab.ui.control.Button
        RegularizerLabel                matlab.ui.control.Label
        DropDownRegul                   matlab.ui.control.DropDown
        CheckBoxPositivity              matlab.ui.control.CheckBox
        FMPanel_CostFunction_2          matlab.ui.container.Panel
        NotationDF1                     matlab.ui.control.Label
        NameDF1                         matlab.ui.control.Label
        ButtonSetParamsDF1              matlab.ui.control.Button
        ButtonDeleteDF1                 matlab.ui.control.Button
        ButtonInfoDF                    matlab.ui.control.Button
        ButtonInfoRegul                 matlab.ui.control.Button
        ButtonSaveCostPanel             matlab.ui.control.Button
        ButtonCheckPipelineCostPanel    matlab.ui.control.Button
        Optimization                    matlab.ui.container.Tab
        FMPanel_Optimization            matlab.ui.container.Panel
        NameOpti1                       matlab.ui.control.Label
        ButtonSetParamsOpti1            matlab.ui.control.Button
        ButtonDeleteOpti1               matlab.ui.control.Button
        ButtonSelectOpti                matlab.ui.control.Button
        ButtonSeeDocOpti                matlab.ui.control.Button
        AlgorithmLabel                  matlab.ui.control.Label
        DropDownOpti                    matlab.ui.control.DropDown
        ButtonInfoOpti                  matlab.ui.control.Button
        ButtonRUN                       matlab.ui.control.Button
        ButtonGenerateScript            matlab.ui.control.Button
        ButtonParametersRecap           matlab.ui.control.Button
        CheckBoxCostEvolDisp            matlab.ui.control.CheckBox
        ButtonSaveOptiPanel             matlab.ui.control.Button
        ButtonCheckPipelineAlgoPanel    matlab.ui.control.Button
        GENERALOPTIONSLabel             matlab.ui.control.Label
        ButtonInfoUseGPU                matlab.ui.control.Button
        ButtonInfoCostEvolDisp          matlab.ui.control.Button
        CheckBoxDiscardDefaultParams    matlab.ui.control.CheckBox
        ButtonInfoDiscardDefaultParams  matlab.ui.control.Button
        CheckBoxNormLinOps              matlab.ui.control.CheckBox
        ButtonInfoNormLinOps            matlab.ui.control.Button
        CheckBoxPreCompute              matlab.ui.control.CheckBox
        ButtonInfoPreCompute            matlab.ui.control.Button
        ButtonBrowseResultFile          matlab.ui.control.Button
        UseGPUDropDownLabel             matlab.ui.control.Label
        DropDownUseGPU                  matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ButtonRefreshAlgorithmsList     matlab.ui.control.Button
        FiletosavetheresultEditFieldLabel  matlab.ui.control.Label
        EditFieldResultPath             matlab.ui.control.EditField
        ButtonInfoDisplay               matlab.ui.control.Button
        DisplayLabel                    matlab.ui.control.Label
        DropDownDisplay                 matlab.ui.control.DropDown
    end

    % ======================================
    % Programatically added private Properties
    % ======================================
    properties (Access = private)
        
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation
        pathToGlobalBioIm                  % path to the GUI folder
        
        % Properties for ForwardModel panel
        maxLinOp         = 6;      % maximum number of concatenable linOps
        
        % Properties for CostFunction panel
        maxDF            = 1;      % maximum number of data fidelity terms
        maxRegul         = 3;      % maximum number of regularization terms
        DFText                     % text to display for selected DF
        RegulText                  % text to display for selected Reguls
        PosText                    % text to display for selected Positivity
        dfListGUI        = {'Quadratic (L2)', 'Kullback-Leibler (KL)'}; % proposed data-fidelity terms (in GUI)
        dfListCode       = {'L2', 'KullLeib'};                          % corresponding data-fidelity terms (in code)
        
        % Properties for Optimization panel
        maxOpti          = 1;      % maximum number of algorithm (to be coherent)
        listOpti         ={};      % list of admissible Optimization algorithm (updated at each click on the Opti Panel, see TabChanged method)
        ParamRecapFig               % figure for final recap of param.
        OptiListCode
        
        % Information for display
        incr_height   = 50;
        def_height    = 20;
        
    end
    
    % ======================================
    % Programatically added public Properties
    % ======================================
    properties (Access = public)
        
        LinOpCell           % Cell array containing the LinOps (ForwardModel panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the LinOp)
                            %   - notation (letter/symbol used to represent the LinOp)
                            %   - call     (command line used to instanciate the LinOp)
                            %   - parameters (cell with fields: name/type/val/info)
        
        DFCell              % Cell array containing the data-fidelity term (CostFunction panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the DF)
                            %   - notation (letter/symbol used to represent the DF)
                            %   - call     (command line used to instanciate the DF)
                            %   - parameters (cell with fields: name/type/val/info)
        
        RegulCell           % Cell array containing the regularizations (CostFunction panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the Regul)
                            %   - notation (letter/symbol used to represent the Regul)
                            %   - call     (command line used to instanciate the Regul)
                            %   - parameters (cell with fields: name/type/val/info)
                            
        OptiCell            % Cell array containing the minimzation algorithms (Optimization panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the Opti)
                            %   - notation (letter/symbol used to represent the Opti)
                            %   - call     (command line used to instanciate the Opti)
                            %   - parameters (cell with fields: name/type/val/info)
                            
        isPositivity        % Boolean true if positivity is activated                 
        
        SetParamsFigs       % Cell array used to manage the set parameters figures
        nbSetParamsFigs     % Number of opened Set Parameters Figures

        logwd=[]            % Log window          
        
        script=struct('header',{{'%----------------------------------------------'}}, ...
            'params',{{'%% Load/Read Parameters'}}, ...
            'gpuConverter',{{'%% GPU/CPU converter'}}, ...
            'linop',{{'%% Instanciate the Forward Model'}}, ...
            'cost',{{'%% Instanciate the Cost function'}}, ...
            'algo',{{'%% Instanciate and Run the Optimization method'}}, ...
            'save',{{'%% Display and Save Results'}});
        
        pipeline            % structure with fields
                            %    -name  (name of the pipeline)
                            %    -dscpt (description of the pipeline)
                            
        % General parameters
        discardDefaultParams % Boolean true to discard default parameters in script  
        display              % to select the way of displaying the result
        useGPU               % to select the GPU mode
        doPrecompute         % boolean true if precomputations are activated
        dispCostEvol         % boolean (TODO: for later)
        computeNormLinOp     % boolean (TODO: for later)
        savePath=[];         % path to save the result
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    % ======================================
    %           COMMON METHODS
    % ======================================
    methods (Access = private)    
        % -- Init properties
        function InitProperties(app)           
            % Clean figures if necessary
            for ii=1:length(app.SetParamsFigs)
                if ~isempty(app.SetParamsFigs{ii})
                    app.SetParamsFigs{ii}.fig.delete;
                end
            end
            app.SetParamsFigs=cell(1,app.maxLinOp+app.maxDF+app.maxRegul+app.maxOpti);
            app.nbSetParamsFigs=struct('LinOp',0,'DF',0,'Regul',0, 'Opti', 0);
            app.LinOpCell={};app.DFCell={};app.RegulCell={};app.OptiCell={}; 
            ResetScript(app);
            app.isPositivity=false; app.CheckBoxPositivity.Value = app.isPositivity;
            app.discardDefaultParams=true; app.CheckBoxDiscardDefaultParams.Value=app.discardDefaultParams;
            app.useGPU=0;app.DropDownUseGPU.Value='Off';
            app.display = app.DropDownDisplay.Value;
            app.doPrecompute = false;app.CheckBoxPreCompute.Value=app.doPrecompute;
            app.dispCostEvol = true;app.CheckBoxCostEvolDisp.Value=app.dispCostEvol;
            app.computeNormLinOp = true;app.CheckBoxNormLinOps.Value=app.computeNormLinOp;   
            s = what('GlobalBioIm');
            if length(s)~=1
                app.pathToGlobalBioIm = uigetdir(cd,['Select a GlobalBioIm Folder among the ',num2str(length(s)),' present on your computer']);
            else
                app.pathToGlobalBioIm = s.path;
            end                           
        end
    
        % -- Reset Script
        function ResetScript(app)
            app.script=struct('header',{{'%----------------------------------------------'}}, ...
                'params',{{'%% Load/Read Parameters'}}, ...
                'gpuConverter',{{'%% GPU/CPU converter'}}, ...
                'linop',{{'%% Instanciate the Forward Model'}}, ...
                'cost',{{'%% Instanciate the Cost function'}}, ...
                'algo',{{'%% Instanciate and Run the Optimization method'}},...
                'save',{{'%% Display and Save Results'}});
        end
        
        % -- Generate Script
        function [err,msg]=GenerateAndCheckScript(app)
            % Reset Script
            ResetScript(app);
            
            % ======================================
            %           UPDATE HEADER
            % ======================================
            app.script.header{length(app.script.header)+1} = ['% ',app.EditFieldPipeline.Value];
            app.script.header{length(app.script.header)+1} = '%';
            app.script.header{length(app.script.header)+1} = '% Description:';
            app.script.header=[app.script.header,transpose(cellfun(@(x) ['%    ',x],app.TextAreaDescriptionPipeline.Value,'UniformOutput',false))];
            app.script.header{length(app.script.header)+1} = '%';
            app.script.header{length(app.script.header)+1} = ['% Generated on ',date,' using the GUI of the GlobalBioIm library'];
            app.script.header{length(app.script.header)+1} = '% Link to GlobalBioIm: <https://biomedical-imaging-group.github.io/GlobalBioIm/>';
            app.script.header{length(app.script.header)+1} = '%----------------------------------------------';
            app.script.header{length(app.script.header)+1} = ['useGPU(',num2str(app.useGPU),');'];
            
                                               
            %==============================
            % Check Forward Model
            %==============================
            % Initialize error message
            errMsgLinOp=[{'## Check Forward Model : '},{' '}];
            errLinOp=0;
            if isempty(app.LinOpCell)
                errLinOp=errLinOp+1;
                errMsgLinOp=[errMsgLinOp,['  (#',num2str(errLinOp),') Empty list of LinOps : At least a LinOpIdentity must be selected.'],' '];
            end
            % Check parameters section
            for idx=1:length(app.LinOpCell)       % Loop over LinOps
                Op=['H',num2str(idx)];
                app.script.params{length(app.script.params)+1} = ['% - Operator-',num2str(idx),' : ',app.LinOpCell{idx}.name];  % Comment with Name
                msg=UpdateScript_ParametersSec(app,app.LinOpCell{idx},Op);
                if ~isempty(msg)
                    errLinOp=errLinOp+1;
                    msg{1}=['  (#',num2str(errLinOp),') ',msg{1}];
                    for ii=2:length(msg), msg{ii}=['       ',msg{ii}]; end
                    errMsgLinOp=[errMsgLinOp,msg];
                end                
            end
            try
                eval(sprintf('%s\n',app.script.params{:}));
            catch ME
                errLinOp=errLinOp+1;
                errMsgLinOp=[errMsgLinOp,['  (#',num2str(errLinOp),') In LinOps parameters setting: ',ME.message],' '];
            end
                       
            % Check LinOp Instanciation
            instOk=1;
            for idx=1:length(app.LinOpCell)               
                Op=['H',num2str(idx)];                                          
                app.script.linop{length(app.script.linop)+1} = ['% - Operator-',num2str(idx),' : ',app.LinOpCell{idx}.name];  % Comment with LinOp Name
                app.script.linop{length(app.script.linop)+1} = UpdateScript_SetPrefixToCalls(app,[Op,' = ',app.LinOpCell{idx}.call,';'],app.LinOpCell{idx},Op);  % Instanciation line    
                try
                    eval(sprintf('%s\n',app.script.linop{end}));
                catch ME
                    errLinOp=errLinOp+1;instOk=0;
                    errMsgLinOp=[errMsgLinOp,['  (#',num2str(errLinOp),') In ',app.LinOpCell{idx}.name,': ',ME.message],' '];
                end
            end
            
            % Check sizes input and output
            if instOk
                for idx=2:length(app.LinOpCell)
                    eval(['b = cmpSize(H',num2str(idx-1),'.sizein,H',num2str(idx),'.sizeout);'])
                    if ~b
                        errLinOp=errLinOp+1;
                        errMsgLinOp=[errMsgLinOp,['  (#',num2str(errLinOp),') The input size of ',app.LinOpCell{idx-1}.name,' [',num2str(eval(['H',num2str(idx-1),'.sizein'])), ... 
                                                           '] does not match the output size of ',app.LinOpCell{idx}.name,' [',num2str(eval(['H',num2str(idx),'.sizeout'])),']'],' '];
                    end
                end
            end

            % Test if failed
            if length(errMsgLinOp) >2
                errMsgLinOp{1} = [errMsgLinOp{1},' Failed ! (',num2str(errLinOp),' errors found)'];
            else
                errMsgLinOp{1} = [errMsgLinOp{1},' Ok ! (',num2str(errLinOp),' errors found)'];
            end
            
            %==============================
            % Check Cost Function
            %==============================
            % Initialize error message
            errMsgCost=[{'## Check Cost Function : '},{' '}];
            errCost=0;
            endParam=length(app.script.params);
            % Check parameters section DF
            for idx=1:length(app.DFCell)       % Loop over DF
                app.script.params{length(app.script.params)+1} = ['% - Data-fidelity : ',app.DFCell{idx}.name];  % Comment with Name
                msg=UpdateScript_ParametersSec(app,app.DFCell{idx},'DF');    
                if ~isempty(msg)
                    errCost=errCost+1;
                    msg{1}=['  (#',num2str(errCost),') ',msg{1}];
                    for ii=2:length(msg), msg{ii}=['       ',msg{ii}]; end
                    errMsgCost=[errMsgCost,msg];
                end
            end
            for idx=1:length(app.RegulCell)     % Regularization
                R=['R',num2str(idx)];
                app.script.params{length(app.script.params)+1} = ['% - Regularization-',num2str(idx),' : ',app.RegulCell{idx}.name];  % Comment with Name
                msg=UpdateScript_ParametersSec(app,app.RegulCell{idx},R);
                if ~isempty(msg)
                    errCost=errCost+1;
                    msg{1}=['  (#',num2str(errCost),') ',msg{1}];
                    for ii=2:length(msg), msg{ii}=['       ',msg{ii}]; end
                    errMsgCost=[errMsgCost,msg];
                end
            end
            try
                eval(sprintf('%s\n',app.script.params{endParam+1:end}));
            catch ME
                errCost=errCost+1;
                errMsgCost=[errMsgCost,['  (#',num2str(errCost),') In LinOps parameters setting: ',ME.message],' '];
            end
            
            % Check Costs Instanciation
            for idx=1:length(app.DFCell)
                app.script.cost{length(app.script.cost)+1} = ['% - Data-Fidelity : ',app.DFCell{idx}.name];       % Comment with DF Name
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,['DF = ',app.DFCell{idx}.call,';'],app.DFCell{idx},'DF');
                app.script.cost{end}=strrep(app.script.cost{end},'InputSize','H1.sizeout');% handle InputSize argument
                try
                    eval(sprintf('%s\n',app.script.cost{end}));
                catch ME
                    errCost=errCost+1;
                    errMsgCost=[errMsgCost,['  (#',num2str(errCost),') In ',app.DFCell{idx}.name,': ',ME.message],' '];
                end
            end
            nbLinOps=length(app.LinOpCell);
            for idx=1:length(app.RegulCell)
                OpReg=['OpReg',num2str(idx)];
                CostReg=['CostReg',num2str(idx)];
                app.script.cost{length(app.script.cost)+1} = ['% - Regularization-',num2str(idx),' : ',app.RegulCell{idx}.name];  % Comment with Regul Name
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,[OpReg,' = ',app.RegulCell{idx}.callOpReg,';'],app.RegulCell{idx},['R',num2str(idx)]);  % Instanciation line OpReg
                app.script.cost{end}=strrep(app.script.cost{end},'InputSize',['H',num2str(nbLinOps),'.sizein']);% handle InputSize argument
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,[CostReg,' = R',num2str(idx),'_lambda * ',app.RegulCell{idx}.callCostReg,';'],app.RegulCell{idx},['R',num2str(idx)]);  % Instanciation line OpReg
                app.script.cost{end}=strrep(app.script.cost{end},'OpReg',OpReg); % handle occurences of OpReg 
                app.script.cost{end}=strrep(app.script.cost{end},'InputSize',['H',num2str(nbLinOps),'.sizein']);
                try
                    eval(sprintf('%s\n',app.script.cost{end-1:end}));
                catch ME
                    errCost=errCost+1;
                    errMsgCost=[errMsgCost,['  (#',num2str(errCost),') In ',app.RegulCell{idx}.name,': ',ME.message],' '];
                end
            end
            
            % Test if failed
            if length(errMsgCost) >2
                errMsgCost{1} = [errMsgCost{1},' Failed ! (',num2str(errCost),' errors found)'];
            else
                errMsgCost{1} = [errMsgCost{1},' Ok ! (',num2str(errCost),' errors found)'];
            end
            
            %==============================
            % Check Opti
            %==============================
            % Initialize error message
            errMsgOpti=[{'## Check Optimization Algorithm : '},{' '}];
            errOpti=0;
            endParam=length(app.script.params);
            codeSetParamsOpti={};
            % Check parameters section
            for idx=1:length(app.OptiCell)       % Loop over LinOps
                Opt='Opt';
                app.script.params{length(app.script.params)+1} = ['% - Algorithm ',app.OptiCell{idx}.name];  % Comment with Name
                msg=UpdateScript_ParametersSec(app,app.OptiCell{idx},Opt);
                if ~isempty(msg)
                    errOpti=errOpti+1;
                    msg{1}=['  (#',num2str(errOpti),') ',msg{1}];
                    for ii=2:length(msg), msg{ii}=['       ',msg{ii}]; end
                    errMsgOpti=[errMsgOpti,msg];
                end       
                for jj=1:length(app.OptiCell{idx}.parameters)
                    if ~app.discardDefaultParams || ~isequal(app.OptiCell{idx}.parameters{jj}.val,app.OptiCell{idx}.parameters{jj}.default)
                        if app.OptiCell{idx}.parameters{jj}.toSet 
                            codeSetParamsOpti = [codeSetParamsOpti,[Opt,'.',app.OptiCell{idx}.parameters{jj}.name,' = ',Opt,'_',app.OptiCell{idx}.parameters{jj}.name,';']];
                        end
                    end
                end
            end
            try
                eval(sprintf('%s\n',app.script.params{endParam+1:end}));
            catch ME
                errOpti=errOpti+1;
                errMsgOpti=[errMsgOpti,['  (#',num2str(errOpti),') In Opti parameters setting: ',ME.message],' '];
            end
            
            % Check Algo Instanciation
            instOk=1;
            for idx=1:length(app.OptiCell)               
                Opt='Opt';                                          
                app.script.algo{length(app.script.algo)+1} = ['% - Algorithm ',app.OptiCell{idx}.name];  % Comment with LinOp Name
                app.script.algo = [app.script.algo,UpdateScript_SetPrefixToCalls(app,app.OptiCell{idx}.call,app.OptiCell{idx},Opt)];    
                try
                    eval(sprintf('%s\n',app.script.algo{:}));
                catch ME
                    errOpti=errOpti+1;instOk=0;
                    errMsgOpti=[errMsgOpti,['  (#',num2str(errOpti),') In ',app.OptiCell{idx}.name,': ',ME.message],' '];
                end
            end
            
            % Check Run            
            if instOk
                for idx=1:length(app.OptiCell)
                    app.script.algo = [app.script.algo,codeSetParamsOpti];                
                    try
                        eval(sprintf('%s\n',app.script.algo{:}));
                        eval(sprintf('%s\n',['Opt.verbose=false;Opt.maxiter = 1;Opt.run(zeros(Opt.cost.sizein));']));
                    catch ME
                        errOpti=errOpti+1;
                        errMsgOpti=[errMsgOpti,['  (#',num2str(errOpti),') In ',app.OptiCell{idx}.name,': ',ME.message],' '];
                    end
                    if ~isempty(app.OptiCell) && isfield(app.OptiCell{1},'costIndex')
                        app.script.algo = [app.script.algo,['Opt.OutOp = OutputOpti(1,round(Opt.maxiter/10),[',num2str(app.OptiCell{idx}.costIndex),']);']];
                    else
                        app.script.algo = [app.script.algo,'Opt.OutOp = OutputOpti(1,round(Opt.maxiter/10));'];
                    end
                    app.script.algo = [app.script.algo,'Opt.ItUpOut = round(Opt.maxiter/10);'];
                    app.script.algo = [app.script.algo,'Opt.CvOp = TestCvgCombine(''CostRelative'',Opt_TolCost, ''StepRelative'',Opt_TolStep);'];
                    app.script.algo = [app.script.algo,'Opt.run(zeros(Opt.cost.sizein));'];                    
                end
            end

            % Test if failed
            if length(errMsgOpti) >2
                errMsgOpti{1} = [errMsgOpti{1},' Failed ! (',num2str(errOpti),' errors found)'];
            else
                errMsgOpti{1} = [errMsgOpti{1},' Ok ! (',num2str(errOpti),' errors found)'];
            end
                        
            %==============================
            % Save results
            %==============================     
            errMsgSave=[{'## Check Save File : '},{' '}];
            errSave=0;
            if exist('Opt','var')
                switch app.display
                    case '2D Grayscale'
                        app.script.save{length(app.script.save)+1} = ['imdisp(Opt.xopt,[''',app.pipeline.name,'-Result''],1);'];
                    case '2D Color'
                        app.script.save{length(app.script.save)+1} = ['image((Opt.xopt/max(Opt.xopt(:))).*ones([Opt.cost.sizein(1:2),3])); title([''',app.pipeline.name,'-Result''],''FontSize'',14); axis image; axis off;'];
                    case '3D Orthoviews'
                        app.script.save{length(app.script.save)+1} = ['Orthoviews(Opt.xopt,[],[''',app.pipeline.name,'-Result''],1);'];
                end
                try
                    eval(sprintf('%s\n',app.script.save{:}));close all;
                catch ME
                    errSave=errSave+1;close all;
                    errMsgSave=[errMsgSave,['  (#',num2str(errSave),') Incompatible display method : ',ME.message],' '];
                end
                
                % Save
                if ~isempty(app.savePath)
                    ext = regexp(app.savePath,'\.','split');
                    app.script.params{length(app.script.params)+1} = '% - Path to save results';
                    app.script.params{length(app.script.params)+1}  = ['resultPath = ''',ext{1},''';'];
                    app.script.save{length(app.script.save)+1}='save([resultPath,''_OptiCell''],''Opt'');';
                    switch ext{end}
                        case 'tif'
                            app.script.save{length(app.script.save)+1}=['saveastiff(single(Opt.xopt),[resultPath,''.',ext{end},''']);'];
                        case {'png','jpg'}
                            app.script.save{length(app.script.save)+1}=['imwrite(uint8(Opt.xopt/max(Opt.xopt(:))*255),[resultPath,''.',ext{end},''']);'];
                        case 'mat'
                            app.script.save{length(app.script.save)+1}='xopt=Opt.xopt; save(resultPath,''xopt'');';
                        otherwise
                            errMsgSave{length(errMsgSave)+1}=['Unknown file extension .',ext{end}];
                            errMsgSave{length(errMsgSave)+1}='    --> Accepted formats: .tif, .png, .jpg, .mat';
                            errMsgSave{length(errMsgSave)+1}=' ';
                            errSave=errSave+1;
                    end
                end
            end
            

            % == Return errors
            msg=[errMsgLinOp,' ',errMsgCost,' ',errMsgOpti,' ',errMsgSave];
            if (errLinOp + errCost + errOpti +errSave)>0
                err=1;
            else
                err=0;
            end   
        end
    
        % -- InitPipeline
        function InitPipeline(app,choice)
            % Hide
            HideLinOp(app);
            HideDF(app);
            HideRegul(app);
            HideOpti(app); 
            % Re-Initialize
            InitProperties(app);  
            % Get selected pipeline
            enable=true;
            switch choice
                case 'New'                  
                    app.EditFieldPipeline.Value = 'MyPipeline';
                    app.pipeline.name=app.EditFieldPipeline.Value;
                    app.TextAreaDescriptionPipeline.Value = 'Describe your pipeline';
                    app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;                                      
                otherwise
                    cont=true;
                    if strcmp(choice,'Browse')
                        [file,path]=uigetfile('*.mat');
                        BringAppFront(app);                 
                        if file~=0
                            Pipeline=load([path,file]);
                        else
                            cont=false;
                            enable=false;
                        end
                    else
                        Pipeline=load(['pipelines/',choice]);
                    end
                    if cont
                        try
                            app.LinOpCell=Pipeline.LinOpCell;
                            app.DFCell=Pipeline.DFCell;
                            app.RegulCell=Pipeline.RegulCell;
                            app.pipeline=Pipeline.pipeline;
                            app.isPositivity=Pipeline.isPositivity; app.CheckBoxPositivity.Value = app.isPositivity;
                            app.discardDefaultParams=Pipeline.discardDefaultParams; app.CheckBoxDiscardDefaultParams.Value=app.discardDefaultParams;
                            app.display=Pipeline.display;
                            app.DropDownDisplay.Value=app.display;
                            app.useGPU= Pipeline.useGPU;
                            switch app.useGPU
                                case 0
                                    app.DropDownUseGPU.Value='Off';
                                case 1
                                    app.DropDownUseGPU.Value='On (Matlab Toolbox)';
                                case 2
                                    app.DropDownUseGPU.Value='On (CudaMat)';
                            end
                            app.doPrecompute=Pipeline.doPrecompute; app.CheckBoxPreCompute.Value=app.doPrecompute;
                            app.dispCostEvol=Pipeline.dispCostEvol; app.CheckBoxCostEvolDisp.Value=app.dispCostEvol;
                            app.computeNormLinOp=Pipeline.computeNormLinOp; app.CheckBoxNormLinOps.Value=app.computeNormLinOp;
                            app.EditFieldPipeline.Value = app.pipeline.name;
                            app.TextAreaDescriptionPipeline.Value = app.pipeline.dscpt;
                            app.savePath = Pipeline.savePath; app.EditFieldResultPath.Value = ['...',app.savePath(max(1,length(app.savePath)-30):end)];;
                            ButtonPushed_RefreshAlgorithmsList(app,[]);
                            app.OptiCell=Pipeline.OptiCell;
                        catch ME
                            OpenLog(app,'The loaded file is not a valid pipeline','red');
                            enable=0;
                        end
                    end
            end
            % Show
            if enable
                set( findall(app.GlobalBioImGUIUIFigure, '-property', 'Enable'), 'Enable', 'on');
                if isempty(app.OptiCell)
                    app.ButtonRUN.Enable ='off';
                end
                ShowLinOp(app);
                ShowDF(app);
                ShowOpti(app);
                ShowRegul(app);
            else
                set( findall(app.GlobalBioImGUIUIFigure, '-property', 'Enable'), 'Enable', 'off');
                set( findall(app.Main, '-property', 'Enable'), 'Enable', 'on');
            end
        end
     
    
        % -- Open Log window
        function OpenLog(app,msg,col)
            if (~isempty(app.logwd) && ishandle(app.logwd.fig)), close(app.logwd.fig); end
            app.logwd.fig = uifigure;
            app.logwd.fig.Position =app.GlobalBioImGUIUIFigure.Position  +  [0 app.GlobalBioImGUIUIFigure.Position(4)/3 0 -app.GlobalBioImGUIUIFigure.Position(4)*2/3];
            app.logwd.fig.Name  = 'Log';
            app.logwd.TextArea = uitextarea(app.logwd.fig);
            app.logwd.TextArea.Value = msg;
            if isequal(col,'green')
                app.logwd.TextArea.FontColor=[0 0.5 0];
            else
                app.logwd.TextArea.FontColor=col;
            end
            app.logwd.TextArea.Position = [10 10 app.logwd.fig.Position(3)-20 app.logwd.fig.Position(4)-20];
            app.logwd.TextArea.Editable = 'on';
        end
        
        % -- Bring the app to the front
        function BringAppFront(app)
            %app.GlobalBioImGUIUIFigure.Visible = 'off'; app.GlobalBioImGUIUIFigure.Visible = 'on';
            figure(app.GlobalBioImGUIUIFigure)
            for ii=1:length(app.SetParamsFigs)
                if ~isempty(app.SetParamsFigs{ii}) && strcmp(app.SetParamsFigs{ii}.fig.Visible,'on')
                    %app.SetParamsFigs{ii}.fig.Visible = 'off'; app.SetParamsFigs{ii}.fig.Visible = 'on';
                    figure(app.SetParamsFigs{ii}.fig)
                end
            end
            if ~isempty(app.ParamRecapFig) && ishandle(app.ParamRecapFig.fig)
                %app.ParamRecapFig.fig.Visible = 'off'; app.ParamRecapFig.fig.Visible = 'on'; 
                figure(app.ParamRecapFig.fig)
            end
        end

        % -- Value Changed function: ValueChanged_SetParamsFigTextEdit
        function ValueChanged_SetParamsFigTextEdit(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end

            if strcmp(event.Source.Parent.Name,'Recap of Selected Parameters')
                tmp=app.ParamRecapFig.params{idx2}{ii}.EditField.Value;         % To remove inserted spaces at the end
                tmp=tmp(find(~(tmp==' '),1,'first'):find(~(tmp==' '),1,'last'));
                app.ParamRecapFig.params{idx2}{ii}.EditField.Value = regexprep(tmp,' +',' ');
                if ~isempty(app.SetParamsFigs{idx2})
                    app.SetParamsFigs{idx2}.param{ii}.EditField.Value=app.ParamRecapFig.params{idx2}{ii}.EditField.Value;
                end
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.ParamRecapFig.params{idx2}{ii}.EditField.Value;']);
            else
                tmp=app.SetParamsFigs{idx2}.param{ii}.EditField.Value;         % To remove inserted spaces at the end
                tmp=tmp(find(~(tmp==' '),1,'first'):find(~(tmp==' '),1,'last'));
                app.SetParamsFigs{idx2}.param{ii}.EditField.Value = regexprep(tmp,' +',' ');
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.EditField.Value;']);
            end
        end
    
        % -- Value Changed function: ValueChanged_SetParamsFigNumEdit
        function ValueChanged_SetParamsFigNumEdit(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            if strcmp(event.Source.Parent.Name,'Recap of Selected Parameters')
                if ~isempty(app.SetParamsFigs{idx2})
                    app.SetParamsFigs{idx2}.param{ii}.EditField.Value=app.ParamRecapFig.params{idx2}{ii}.EditField.Value;
                end
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=num2str(app.ParamRecapFig.params{idx2}{ii}.EditField.Value);']);
            else
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=num2str(app.SetParamsFigs{idx2}.param{ii}.EditField.Value);']);
            end
        end
    
        % -- Value changed function: ValueChanged_SetParamsFigDropDown
        function ValueChanged_SetParamsFigDropDown(app, event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            if strcmp(event.Source.Parent.Name,'Recap of Selected Parameters')
                if ~isempty(app.SetParamsFigs{idx2})
                    app.SetParamsFigs{idx2}.param{ii}.dropDown.Value=app.ParamRecapFig.params{idx2}{ii}.dropDown.Value;
                end
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.ParamRecapFig.params{idx2}{ii}.dropDown.Value;']);
            else
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.dropDown.Value;']);
            end
        end
        
        % -- Value changed function: ValueChanged_SetParamsFigBoolean
        function ValueChanged_SetParamsFigBoolean(app, event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            if strcmp(event.Source.Parent.Name,'Recap of Selected Parameters')
                if ~isempty(app.SetParamsFigs{idx2})
                    app.SetParamsFigs{idx2}.param{ii}.boolean.Value=app.ParamRecapFig.params{idx2}{ii}.boolean.Value;
                end
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.ParamRecapFig.params{idx2}{ii}.boolean.Value;']);
            else
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.boolean.Value;']);
            end
        end

        % -- Button Pushed function: ButtonPushed_SetParamsFigBrowse
        function ButtonPushed_SetParamsFigBrowse(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            [file,path]=uigetfile('*.*','Select a file');                  
            BringAppFront(app);
            if file~=0
                tt=[path,file];tt=['...',tt(end-30:end)];     
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=[path,file];']);
                if strcmp(event.Source.Parent.Name,'Recap of Selected Parameters')
                    app.ParamRecapFig.params{idx2}{ii}.TextArea.Value=tt;
                end
                if ~isempty(app.SetParamsFigs{idx2})                   
                    app.SetParamsFigs{idx2}.param{ii}.TextArea.Value=tt;
                end
            end
        end
    
        % -- Button pushed function: ButtonPushed_ApplyChanges
        function ButtonPushed_ApplyChanges(app,event)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    pp=app.LinOpCell{idx}.parameters;
                    nButtom=length(app.LinOpCell);
                    idx2=idx;
                case 'DF'
                    pp=app.DFCell{idx}.parameters;
                    nButtom=length(app.DFCell);
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    pp=app.RegulCell{idx}.parameters;
                    nButtom=length(app.RegulCell);
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    pp=app.OptiCell{idx}.parameters;
                    nButtom=length(app.OptiCell);
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            % Check if syntax error            
            err=0;msg={};
            for ii=1:length(pp)
                typ=pp{ii}.type;
                switch  typ
                    case 'vecInt'
                        pp{ii}.val=pp{ii}.val(1:find(~(pp{ii}.val==' '),1,'last'));  % To remove inserted spaces at the end
                        pp{ii}.val = regexprep(pp{ii}.val,' +',' ');
                        app.SetParamsFigs{idx2}.param{ii}.EditField.Value=pp{ii}.val;
                        tmp=arrayfun(@(x) [num2str(x),' '],str2num(pp{ii}.val),'UniformOutput',false);
                        tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                        if ~isempty(pp{ii}.val) &&  (~strcmp(tmp,pp{ii}.val) || all(mod(str2num(pp{ii}.val),1)~=0))
                            err=1;
                            msg{length(msg)+1}=['Wrong entry for parameter ',pp{ii}.name];
                            msg{length(msg)+1}='    --> A sequence of integers separated by a space is required (e.g. 512 512)';
                            msg{length(msg)+1}=' ';
                            app.SetParamsFigs{idx2}.param{ii}.label.FontColor='red';
                        else
                            app.SetParamsFigs{idx2}.param{ii}.label.FontColor='black';
                        end
                end
            end
            % If no errors make the figure visible off
            if err==0
                app.SetParamsFigs{idx2}.fig.Visible='off';
                eval(['app.nbSetParamsFigs.',tag,'=app.nbSetParamsFigs.',tag,'-1;']); % Increase the counter of opened Set Params Figs
                eval(['bb=(app.nbSetParamsFigs.',tag,'==0);']);
                if bb
                    for ii=1:nButtom
                        if strcmp(tag,'LinOp')
                            app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','on');
                            app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','on');
                        end
                        app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','on');
                    end
                    eval(['enab=(length(app.',tag,'Cell)<app.max',tag,');']);
                    if enab
                        app = setfield(app,['ButtonSelect',tag],'Enable','on');
                    end
                end
                app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','on');
            else
                OpenLog(app,msg,'red');
            end
        end
        
        % -- Button pushed function: ButtonPushed_ApplyChangesRecap
        function ButtonPushed_ApplyChangesRecap(app,event)
        
            % Get number of objects 
            nLinOp = length(app.LinOpCell);
            nDF    = length(app.DFCell);
            nRegul = length(app.RegulCell);
            nOpti  = length(app.OptiCell);
            nObj   = nLinOp+nDF+nRegul+nOpti; 
            
            % Go through all the objects in the cells            
            err=0;msg={};
            for idx=1:nObj
                % case 'LinOp'
                if idx<=nLinOp
                    idx2 = idx;
                    pp=app.LinOpCell{idx2}.parameters;
                    % case 'DF'
                elseif idx>nLinOp && idx<=nLinOp+nDF
                    idx2 = idx-nLinOp;
                    pp=app.DFCell{idx2}.parameters;
                    idx2=idx2+app.maxLinOp;
                    % case 'Regul'
                elseif idx>nLinOp+nDF && idx<=nLinOp+nDF+nRegul
                    idx2 = idx-nLinOp-nDF;
                    pp=app.RegulCell{idx2}.parameters;
                    idx2=idx2+app.maxLinOp +app.maxDF;
                    % case 'Opti'
                else
                    idx2 = idx-nLinOp-nDF-nRegul;
                    pp=app.OptiCell{idx2}.parameters;
                    idx2=idx2+app.maxLinOp +app.maxDF +app.maxRegul;
                end
                
                % Check if syntax error
                for ii=1:length(pp)
                    typ=pp{ii}.type;
                    switch  typ
                        case 'vecInt'
                            tmp=arrayfun(@(x) [num2str(x),' '],str2num(pp{ii}.val),'UniformOutput',false);
                            tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                            if ~isempty(pp{ii}.val) &&  (~strcmp(tmp,pp{ii}.val) || all(mod(str2num(pp{ii}.val),1)~=0))
                                err=1;
                                msg{length(msg)+1}=['Wrong entry for parameter ',pp{ii}.name];
                                msg{length(msg)+1}='    --> A sequence of integers separated by a space is required (e.g. 512 512)';
                                msg{length(msg)+1}=' ';
                                app.ParamRecapFig.params{idx2}{ii}.label.FontColor='red';
                            else
                                app.ParamRecapFig.params{idx2}{ii}.label.FontColor='black';
                            end
                    end
                end
            end
            % If no error, close the figure
            if err==0
                app.nbSetParamsFigs.LinOp=0;
                app.nbSetParamsFigs.DF=0;
                app.nbSetParamsFigs.Regul=0;
                app.nbSetParamsFigs.Opti=0;
                delete(app.ParamRecapFig.fig);
                app.ParamRecapFig={};
                set( findall(app.GlobalBioImGUIUIFigure, '-property', 'Enable'), 'Enable', 'on');
                if isempty(app.OptiCell)
                    app.ButtonRUN.Enable ='off';
                end
                ShowLinOp(app);
                ShowDF(app);
                ShowOpti(app);
                ShowRegul(app);
            else
                OpenLog(app,msg,'red');
            end
        end
        
        % -- Update parameters section in script
        function errMsg=UpdateScript_ParametersSec(app,obj,prefix)            
            errMsg={};
            for ii=1:length(obj.parameters)
                if ~app.discardDefaultParams || ~isequal(obj.parameters{ii}.val,obj.parameters{ii}.default)  % If default parameter, discard it
                    if isempty(obj.parameters{ii}.val)
                        app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = [];'];
                    else
                        typ = obj.parameters{ii}.type;
                        switch  typ
                            case 'vecInt'
                                app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = [',obj.parameters{ii}.val,'];'];
                            case 'file'
                                ext = regexp(obj.parameters{ii}.val,'\.','split');
                                ext=ext{end};
                                switch ext
                                    case 'tif'
                                        app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = double(loadtiff("',obj.parameters{ii}.val,'"));'];
                                    case {'png','jpg'}
                                        app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = double(imread("',obj.parameters{ii}.val,'"));'];
                                    case 'mat'
                                        app.script.params{length(app.script.params)+1}=['tmp=load("',obj.parameters{ii}.val,'"); fd=fields(tmp);'];
                                        app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = tmp.(fd{1});'];
                                    otherwise
                                        errMsg{length(errMsg)+1}=[obj.parameters{ii}.name,' of ',obj.name,' : Unknown file extension .',ext];
                                        errMsg{length(errMsg)+1}='    --> Accepted formats: .tif, .png, .jpg, .mat';
                                        errMsg{length(errMsg)+1}=' ';
                                end
                                app.script.gpuConverter{length(app.script.gpuConverter) +1} = [prefix,'_',obj.parameters{ii}.name,' = gpuCpuConverter(',prefix,'_',obj.parameters{ii}.name,');'];
                            case 'double'
                                app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = ',num2str(obj.parameters{ii}.val),';'];
                            case 'dropDown'
                                app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = ''',obj.parameters{ii}.val,''';'];
                            case 'boolean'
                                app.script.params{length(app.script.params)+1}=[prefix,'_',obj.parameters{ii}.name,' = ',obj.parameters{ii}.val,';'];
                        end
                    end
                end
            end
        end
    
        % -- Update Call with Prefix
        function strUp=UpdateScript_SetPrefixToCalls(app,str,obj,prefix)
            strUp=str;
            for ii=1:length(obj.parameters)
                if app.discardDefaultParams && isequal(obj.parameters{ii}.val,obj.parameters{ii}.default)
                    strUp=strrep(strUp,['(',obj.parameters{ii}.name,','],'(');
                    strUp=strrep(strUp,[',',obj.parameters{ii}.name,','],',[],');
                    strUp=strrep(strUp,[',',obj.parameters{ii}.name,')'],')');
                else
                    strUp=strrep(strUp,[obj.parameters{ii}.name,')'],[prefix,'_',obj.parameters{ii}.name,')']);
                    strUp=strrep(strUp,['(',obj.parameters{ii}.name],['(',prefix,'_',obj.parameters{ii}.name]);
                    strUp=strrep(strUp,[',',obj.parameters{ii}.name,','],[',',prefix,'_',obj.parameters{ii}.name,',']);                    
                end
            end
            strUpOld=strUp;
            while true
                strUp=strrep(strUpOld,',[])',')');  % Finish cleaning-up...
                if strcmp(strUpOld,strUp)
                    break;
                else
                    strUpOld=strUp;
                end
            end
        end
    end
    
    % ======================================
    %          MAIN PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Get list of Pipelines in dropdown menu
        function ListOfPipelines = GetListOfAvailablePipelines(app)
            dirList=struct2cell(dir([app.pathToGlobalBioIm,'/GUI/pipelines/*mat']));
            dirList=dirList(1,:);
            ListOfPipelines=strrep(dirList,'.mat','');
        end
    
        % -- Initialize Main Panel
        function InitializeMainPanel(app)
            ListOfPipelines = GetListOfAvailablePipelines(app);
            app.DropDownPipeline.Items = ListOfPipelines;         
        end
    end
 
    % ======================================
    %      FORWARD MODEL PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Get list of linOps in dropdown menu
        function ListOfLinOpsStr = GetListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir([app.pathToGlobalBioIm,'/LinOp/LinOp*.m']));
            dirList2 = struct2cell(dir([app.pathToGlobalBioIm,'/LinOp/SelectorLinOps/LinOp*.m']));
            dirList3 = struct2cell(dir([app.pathToGlobalBioIm,'/LinOp/LinOp_Utils/AliasLinOp/LinOp*.m']));
            dirList = [dirList1 dirList2,dirList3];
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfLinOpsStr = strrep(strrep(dirList,'.m',''),'LinOp','');
        end
       
        % -- Initialize Forward Model Panel
        function InitializeForwardModelPanel(app)
            % Get the list of LinOps
            ListOfLinOpsStr = GetListOfAvailableLinOps(app);
            
            % Display dropdown GUI list
            app.DropDownLinOp.Items = ListOfLinOpsStr;
            
            % Hide hierarchy of linOps
            HideLinOp(app);
            
            % Set Tags
            app.ButtonSelectLinOp.Tag='LinOp';
            app.ButtonSeeDocLinOp.Tag = 'LinOp'; 
            app.ButtonDeleteLinOp1.Tag='LinOp1';app.ButtonDeleteLinOp2.Tag='LinOp2';app.ButtonDeleteLinOp3.Tag='LinOp3';
            app.ButtonDeleteLinOp4.Tag='LinOp4';app.ButtonDeleteLinOp5.Tag='LinOp5';app.ButtonDeleteLinOp6.Tag='LinOp6';
            app.ButtonUpLinOp1.Tag='LinOp1';app.ButtonUpLinOp2.Tag='LinOp2';app.ButtonUpLinOp3.Tag='LinOp3';
            app.ButtonUpLinOp4.Tag='LinOp4';app.ButtonUpLinOp5.Tag='LinOp5';app.ButtonUpLinOp6.Tag='LinOp6';
            app.ButtonDownLinOp1.Tag='LinOp1';app.ButtonDownLinOp2.Tag='LinOp2';app.ButtonDownLinOp3.Tag='LinOp3';
            app.ButtonDownLinOp4.Tag='LinOp4';app.ButtonDownLinOp5.Tag='LinOp5';app.ButtonDownLinOp6.Tag='LinOp6';
            app.ButtonSetParamsLinOp1.Tag='LinOp1';app.ButtonSetParamsLinOp2.Tag='LinOp2';app.ButtonSetParamsLinOp3.Tag='LinOp3';
            app.ButtonSetParamsLinOp4.Tag='LinOp4';app.ButtonSetParamsLinOp5.Tag='LinOp5';app.ButtonSetParamsLinOp6.Tag='LinOp6';
        end
    
        % -- Hide hierarchy of linOps
        function HideLinOp(app)
            app.NotationLinOp1.Visible='off';app.NotationLinOp2.Visible='off';app.NotationLinOp3.Visible='off';
            app.NotationLinOp4.Visible='off';app.NotationLinOp5.Visible='off';app.NotationLinOp6.Visible='off';
            app.NameLinOp1.Visible='off';app.NameLinOp2.Visible='off';app.NameLinOp3.Visible='off';
            app.NameLinOp4.Visible='off';app.NameLinOp5.Visible='off';app.NameLinOp6.Visible='off';
            app.ButtonSetParamsLinOp1.Visible='off';app.ButtonSetParamsLinOp2.Visible='off';app.ButtonSetParamsLinOp3.Visible='off';
            app.ButtonSetParamsLinOp4.Visible='off';app.ButtonSetParamsLinOp5.Visible='off';app.ButtonSetParamsLinOp6.Visible='off';
            app.ButtonUpLinOp1.Visible='off';app.ButtonUpLinOp2.Visible='off';app.ButtonUpLinOp3.Visible='off';
            app.ButtonUpLinOp4.Visible='off';app.ButtonUpLinOp5.Visible='off';app.ButtonUpLinOp6.Visible='off';
            app.ButtonDownLinOp1.Visible='off';app.ButtonDownLinOp2.Visible='off';app.ButtonDownLinOp3.Visible='off';
            app.ButtonDownLinOp4.Visible='off';app.ButtonDownLinOp5.Visible='off';app.ButtonDownLinOp6.Visible='off';
            app.ButtonDeleteLinOp1.Visible='off';app.ButtonDeleteLinOp2.Visible='off';app.ButtonDeleteLinOp3.Visible='off';
            app.ButtonDeleteLinOp4.Visible='off';app.ButtonDeleteLinOp5.Visible='off';app.ButtonDeleteLinOp6.Visible='off';
        end
        
        % -- Show hierarchy of linOps
        function ShowLinOp(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            LinOpText = 'H = ';
            for idx=1:nLinOps
                % Notation Field
                app=setfield(app,['NotationLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationLinOp',num2str(idx)],'Text',[app.LinOpCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NameLinOp',num2str(idx)],'Text',['LinOp' ,app.LinOpCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsLinOp',num2str(idx)],'Visible','on');
                % Up button
                if idx > 1
                    app=setfield(app,['ButtonUpLinOp',num2str(idx)],'Visible','on');
                end
                % Down button
                if idx < nLinOps
                    app=setfield(app,['ButtonDownLinOp',num2str(idx)],'Visible','on');
                end
                % Delete button
                app=setfield(app,['ButtonDeleteLinOp',num2str(idx)],'Visible','on');                                
                % Update text for concatenation of linOps
                LinOpText = [LinOpText app.LinOpCell{idx}.notation];
            end
            
            % Update text for concatenation of linOps
            app.TextAreaForwardModel.Value = LinOpText;            
            
            % Disable select button if max number reached
            disa=length(app.LinOpCell)>=app.maxLinOp;
            if disa
                app.ButtonSelectLinOp.Enable = "off";
            end  
        end
    end
    
    % ======================================
    %           COST FUNCTION PANEL
    % ======================================
    methods (Access = private) 
        % -- Get list of reguls in dropdown menu
        function ListOfRegulsStr = GetListOfAvailableReguls(app)
            dirList = struct2cell(dir([app.pathToGlobalBioIm,'/GUI/Regul/Regul*.m']));
            ListOfRegulsStr = strrep(strrep(dirList(1,:),'.m',''),'Regul','');
        end
        
        % -- Initialize Cost Function Panel
        function InitializeCostFunctionPanel(app)
            % Get the list of Reguls
            ListOfRegulsStr = GetListOfAvailableReguls(app);
            
            % Display both dropdown GUI lists
            app.DropDownDF.Items = app.dfListGUI;
            app.DropDownRegul.Items = ListOfRegulsStr;
            
            % Hide DF and Regul
            HideDF(app);
            HideRegul(app);
            
            % Set Tags
            app.ButtonSelectDF.Tag = 'DF';
            app.ButtonSelectRegul.Tag = 'Regul';
            app.ButtonSeeDocDF.Tag = 'DF';
            app.ButtonSeeDocRegul.Tag = 'Regul';
            app.ButtonDeleteDF1.Tag='DF1';
            app.ButtonDeleteRegul1.Tag='Regul1';app.ButtonDeleteRegul2.Tag='Regul2';app.ButtonDeleteRegul3.Tag='Regul3';
            app.ButtonSetParamsDF1.Tag='DF1';
            app.ButtonSetParamsRegul1.Tag='Regul1';app.ButtonSetParamsRegul2.Tag='Regul2';app.ButtonSetParamsRegul3.Tag='Regul3';
        end
           
        % -- Hide DF
        function HideDF(app)
            app.NotationDF1.Visible='off';
            app.NameDF1.Visible='off';
            app.ButtonDeleteDF1.Visible='off';
            app.ButtonSetParamsDF1.Visible='off';
        end
        
        % -- Hide Regul
        function HideRegul(app)
            app.NotationRegul1.Visible='off';app.NotationRegul2.Visible='off';app.NotationRegul3.Visible='off';
            app.NameRegul1.Visible='off';app.NameRegul2.Visible='off';app.NameRegul3.Visible='off';
            app.ButtonDeleteRegul1.Visible='off';app.ButtonDeleteRegul2.Visible='off';app.ButtonDeleteRegul3.Visible='off';
            app.ButtonSetParamsRegul1.Visible='off';app.ButtonSetParamsRegul2.Visible='off';app.ButtonSetParamsRegul3.Visible='off';
        end
                
        % -- Show DF
        function ShowDF(app)
            % Number of data fidelity and reguls
            nDF = length(app.DFCell);
            
            % Initialize CostText
            app.DFText = '' ;
            
            % Loop over all the selected data fidelity            
            for idx=1:nDF
                % Notation Field
                app=setfield(app,['NotationDF',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationDF',num2str(idx)],'Text',[app.DFCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameDF',num2str(idx)],'Visible','on');
                app=setfield(app,['NameDF',num2str(idx)],'Text',['Cost' ,app.DFCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsDF',num2str(idx)],'Visible','on');
                % Delete button
                app=setfield(app,['ButtonDeleteDF',num2str(idx)],'Visible','on');                                
                % Update text for concatenation of linOps
                app.DFText = [app.DFText app.DFCell{idx}.notation,'(Hx,y)'];
            end
            
            % Update text for cost
            app.TextAreaCost.Value = ['C(x) = ',app.DFText,app.RegulText, app.PosText];   
            
            % Disable select button if max number reached
            disa=length(app.DFCell)>=app.maxDF;
            if disa
                app.ButtonSelectDF.Enable = "off";
            end  
        end
    
        % -- Show Regul
        function ShowRegul(app)
            % Number of data fidelity and reguls
            nReg = length(app.RegulCell);
            
            % Initialize CostText
            app.RegulText = '' ;
            
            % Loop over all the selected data fidelity
            for idx=1:nReg
                % Notation Field
                app=setfield(app,['NotationRegul',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationRegul',num2str(idx)],'Text',[app.RegulCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameRegul',num2str(idx)],'Visible','on');
                app=setfield(app,['NameRegul',num2str(idx)],'Text',[app.RegulCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsRegul',num2str(idx)],'Visible','on');
                % Delete button
                app=setfield(app,['ButtonDeleteRegul',num2str(idx)],'Visible','on');
                % Update text for concatenation of linOps
                app.RegulText = [app.RegulText ' + ' char(955) char(8320+idx) app.RegulCell{idx}.notation '(x)'];
            end        
            
            % Set positivity
            if app.CheckBoxPositivity.Value
                app.PosText = ' + Pos(x)';
            else
                app.PosText = '';
            end
            
            % Update text for cost
            app.TextAreaCost.Value = ['C(x) = ',app.DFText,app.RegulText, app.PosText];
            
            % Disable select button if max number reached
            disa=length(app.RegulCell)>=app.maxRegul;
            if disa
                app.ButtonSelectRegul.Enable = "off";
            end    
        end    
    end
    
    % ======================================
    %           OPTIMIZATION PANEL
    % ======================================
    methods (Access = private)       
        
        % -- Initialize Optimization Panel
        function InitializeOptimizationPanel(app)
            
            % Display dropdown GUI list
            app.DropDownOpti.Items = {};
            
            % Hide hierarchy of Optis
            HideOpti(app);
            
            % Set Tags
            app.ButtonSelectOpti.Tag='Opti';
            app.ButtonSeeDocOpti.Tag = 'Opti'; 
            app.ButtonDeleteOpti1.Tag='Opti1';
            app.ButtonSetParamsOpti1.Tag='Opti1';
        end
        
        % -- Hide Opti
        function HideOpti(app)
            app.NameOpti1.Visible='off';
            app.ButtonDeleteOpti1.Visible='off';
            app.ButtonSetParamsOpti1.Visible='off';
        end
        
        % -- Show Opti
        function ShowOpti(app)
            if length(app.OptiCell)==1
                % Name Field
                app=setfield(app,'NameOpti1','Visible','on');
                app.NameOpti1.FontColor = 'black';
                app=setfield(app,'NameOpti1','Text',[app.OptiCell{1}.name]);
                % Set parameter button
                app=setfield(app,'ButtonSetParamsOpti1','Visible','on');
                % Delete button
                app=setfield(app,'ButtonDeleteOpti1','Visible','on');
            end
            
            % Disable select button if max number reached
            disa=length(app.OptiCell)>=app.maxOpti;
            if disa
                app.ButtonSelectOpti.Enable = "off";
            end  
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % GENERAL ---------------
            InitProperties(app);
            
            % MAIN PANEL --------------
            InitializeMainPanel(app);
            
            % FORWARD MODEL PANEL ------------------
            InitializeForwardModelPanel(app);
            
            % COST FUNCTION PANEL ------------------
            InitializeCostFunctionPanel(app);      
            
            % COST FUNCTION PANEL ------------------
            InitializeOptimizationPanel(app);   
            
        end

        % Button pushed function: ButtonSeeDocDF, ButtonSeeDocLinOp, 
        % ButtonSeeDocOpti, ButtonSeeDocRegul
        function ButtonPushed_SeeDoc(app, event)
            ll=[];
            if strcmp(event.Source.Parent.Type,'figure') && strcmp(event.Source.Parent.Name,'Set Parameters')
                tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
                idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
                switch tag
                    case 'LinOp'
                        ll = ['linop.html#linop' lower(app.LinOpCell{idx}.name)];
                    case 'DF'
                        id = cellfun(@(x) strcmp(x,app.DFCell{idx}.name),app.dfListCode);
                        ll = ['cost.html#cost' lower(app.dfListCode{id})];
                    case 'Regul'
                        % TODO: SEE WHAT TO DO
                    case 'Opti'
                        id = cellfun(@(x) strcmp(x,app.OptiCell{idx}.name),app.DropDownOpti.Items);
                        ll = ['opti.html#opti' lower(app.OptiListCode{id})];
                end
            else
                tag=event.Source.Tag;
                switch tag
                    case 'LinOp'
                        ll = ['linop.html#linop' lower(app.DropDownLinOp.Value)];
                    case 'DF'
                        idx = strcmp(app.DropDownDF.Items,app.DropDownDF.Value);
                        ll = ['cost.html#cost' lower(app.dfListCode{idx})];
                    case 'Regul'
                        % TODO: SEE WHAT TO DO
                    case 'Opti'
                        idx = strcmp(app.DropDownOpti.Items,app.DropDownOpti.Value);
                        ll = ['opti.html#opti' lower(app.OptiListCode{idx})];
                end
            end
            web([app.doc_weblink ll], '-notoolbar');
        end

        % Button pushed function: ButtonCheckPipelineAlgoPanel, 
        % ButtonCheckPipelineCostPanel, ButtonCheckPipelineFwdPanel
        function ButtonPushed_CheckPipeline(app, event)
            % -- Generate and Check script
            [err,msg]= GenerateAndCheckScript(app);
            
            % -- Open Log Windows
            if err
                OpenLog(app,msg,'red');
            else
                OpenLog(app,msg,'green');
            end
        end

        % Button pushed function: ButtonSelectPipeline
        function ButtonPushed_SelectPipeline(app, event)
            choice=app.DropDownPipeline.Value;
            InitPipeline(app,choice);
        end

        % Button pushed function: ButtonSaveCostPanel, 
        % ButtonSaveFwdPanel, ButtonSaveOptiPanel
        function ButtonPushed_Save(app, event)
            % Get the filename and the path to save the pipeline
            [file,path]=uiputfile('.mat','Save pipeline',[app.pathToGlobalBioIm,'/GUI/pipelines/',app.EditFieldPipeline.Value,'.mat']);
            BringAppFront(app);
            % Save the properties LinOpCell, RegulCell, DFCell, and pipeline
            if file~=0
                LinOpCell = app.LinOpCell;
                RegulCell = app.RegulCell;
                DFCell = app.DFCell;
                OptiCell = app.OptiCell; 
                isPositivity = app.isPositivity;
                pipeline = app.pipeline;
                useGPU = app.useGPU;
                doPrecompute = app.doPrecompute;
                discardDefaultParams = app.discardDefaultParams;
                dispCostEvol= app.dispCostEvol;
                computeNormLinOp=app.computeNormLinOp ;
                savePath = app.savePath;
                display=app.display;
                save([path,file],'LinOpCell','RegulCell','DFCell','OptiCell','pipeline','isPositivity','discardDefaultParams','useGPU','doPrecompute','discardDefaultParams','dispCostEvol','computeNormLinOp','savePath','display');
                clear LinOpCell RegulCell DFCell OptiCell pipeline isPositivity discardDefaultParams useGPU doPrecompute discardDefaultParams dispCostEvol computeNormLinOp savePath display
            end            
        end

        % Value changed function: TextAreaDescriptionPipeline
        function ValueChanged_DescriptionPipeline(app, event)
            app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;
        end

        % Value changed function: EditFieldPipeline
        function ValueChanged_NamePipeline(app, event)
            app.pipeline.name = app.EditFieldPipeline.Value;
        end

        % Button pushed function: ButtonDeleteDF1, 
        % ButtonDeleteLinOp1, ButtonDeleteLinOp2, ButtonDeleteLinOp3, 
        % ButtonDeleteLinOp4, ButtonDeleteLinOp5, ButtonDeleteLinOp6, 
        % ButtonDeleteOpti1, ButtonDeleteRegul1, ButtonDeleteRegul2, 
        % ButtonDeleteRegul3
        function ButtonPushed_Delete(app, event)
            tag=event.Source.Tag(1:end-1);         % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end)); % Get the index of the object to remove
            switch tag
                case 'LinOp'
                    IdxFirst=1;
                    IdxLast=app.maxLinOp;
                case 'DF'
                    IdxFirst=app.maxLinOp+1;
                    IdxLast=app.maxLinOp+app.maxDF;
                case 'Regul'
                    IdxFirst=app.maxLinOp+app.maxDF+1;
                    IdxLast=app.maxLinOp+app.maxDF+app.maxRegul;
                case 'Opti'
                    IdxFirst=app.maxLinOp+app.maxDF+app.maxRegul+1;
                    IdxLast=app.maxLinOp+app.maxDF+app.maxRegul+app.maxOpti;
                    app.ButtonRUN.Enable = 'off';
            end
            % Hide
            eval(['Hide',tag,'(app);']);
            % Update LinOpCell and the SetParamsFigs
            eval(['tmp=app.',tag,'Cell;']);
            tmp2=app.SetParamsFigs;
            if ~isempty(app.SetParamsFigs{IdxFirst-1+idx})
                app.SetParamsFigs{IdxFirst-1+idx}.fig.delete;
            end
            eval(['app.',tag,'Cell={};']);
            app.SetParamsFigs(IdxFirst:IdxLast)=cell(1,IdxLast-IdxFirst+1);
            pos=1;
            for ii=1:length(tmp)
                if ii~=idx
                    eval(['app.',tag,'Cell{pos}=tmp{ii};']);
                    app.SetParamsFigs{IdxFirst-1+pos}=tmp2{IdxFirst-1+ii};
                    pos=pos+1;
                end
            end
            % Show
            eval(['Show',tag,'(app);']);
            % Enable Select button if max number of linOps not reached
            if length(app.LinOpCell)<app.maxLinOp
                eval(['app.ButtonSelect',tag,'.Enable = "on";']);
            end
        end

        % Button pushed function: ButtonDownLinOp1, ButtonDownLinOp2, 
        % ButtonDownLinOp3, ButtonDownLinOp4, ButtonDownLinOp5, 
        % ButtonDownLinOp6
        function ButtonPushed_Down(app, event)
            % Get the index of the object to move down
            idx=str2double(event.Source.Tag(end));   
            % Hide Hierarchy LinOps
            HideLinOp(app);
            % Reorganize the LinOpCell and the SetParamsFigs
            tmp=app.LinOpCell{idx+1};
            app.LinOpCell{idx+1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            tmp=app.SetParamsFigs{idx+1};
            app.SetParamsFigs{idx+1}=app.SetParamsFigs{idx};
            app.SetParamsFigs{idx}=tmp;
            % Show Hierarchy LinOps
            ShowLinOp(app);
        end

        % Button pushed function: ButtonUpLinOp1, ButtonUpLinOp2, 
        % ButtonUpLinOp3, ButtonUpLinOp4, ButtonUpLinOp5, 
        % ButtonUpLinOp6
        function ButtonPushed_Up(app, event)
            % Get the index of the object to move up
            idx=str2double(event.Source.Tag(end));  
            % Hide Hierarchy LinOps
            HideLinOp(app);
            % Reorganize the LinOpCell and the SetParamsFigs
            tmp=app.LinOpCell{idx-1};
            app.LinOpCell{idx-1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            tmp=app.SetParamsFigs{idx-1};
            app.SetParamsFigs{idx-1}=app.SetParamsFigs{idx};
            app.SetParamsFigs{idx}=tmp;
            % Show Hierarchy LinOps
            ShowLinOp(app);
        end

        % Button pushed function: ButtonSetParamsDF1, 
        % ButtonSetParamsLinOp1, ButtonSetParamsLinOp2, 
        % ButtonSetParamsLinOp3, ButtonSetParamsLinOp4, 
        % ButtonSetParamsLinOp5, ButtonSetParamsLinOp6, 
        % ButtonSetParamsOpti1, ButtonSetParamsRegul1, 
        % ButtonSetParamsRegul2, ButtonSetParamsRegul3
        function ButtonPushed_SetParams(app, event)
            tag=event.Source.Tag(1:end-1);             % Get if LinOp / DF / Regul
            eval(['app.nbSetParamsFigs.',tag,'=app.nbSetParamsFigs.',tag,'+1;']);  % Increase the counter of opened Set Params Figs
            idx=str2double(event.Source.Tag(end));     % Get the index of the object whose parameters have to be set
            switch tag
                case 'LinOp'
                    cc=app.LinOpCell{idx}.parameters;
                    idx2=idx;
                case 'DF'
                    cc=app.DFCell{idx}.parameters;
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    cc=app.RegulCell{idx}.parameters;
                    idx2=app.maxLinOp+app.maxDF+idx;    
                case 'Opti'
                    cc=app.OptiCell{idx}.parameters;
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;    
            end
            if isempty(app.SetParamsFigs{idx2})
                % Create Figure
                hh=app.incr_height*(length(cc)+1);
                app.SetParamsFigs{idx2}.fig = uifigure;                
                app.SetParamsFigs{idx2}.fig.Resize = 'off';
                app.SetParamsFigs{idx2}.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
                app.SetParamsFigs{idx2}.fig.Name  = 'Set Parameters';
                set(app.SetParamsFigs{idx2}.fig,'CloseRequestFcn','');  % Disable using the cross to close the window
                               
                % Create Name
                app.SetParamsFigs{idx2}.title = uilabel(app.SetParamsFigs{idx2}.fig);
                app.SetParamsFigs{idx2}.title.FontSize = 16;
                app.SetParamsFigs{idx2}.title.FontWeight = 'bold';
                app.SetParamsFigs{idx2}.title.Position = [19 hh-45 428 38];
                app.SetParamsFigs{idx2}.title.Text = getfield(app,['Name',event.Source.Tag],'Text');
                
                % Create See Doc  button
                app.SetParamsFigs{idx2}.ButtonSeeDoc = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Position = [280 hh-35 50 20];
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Text = 'Doc.';                            
                % TODO : FIX ME (SEE DOC BUTTON)
                app.SetParamsFigs{idx2}.ButtonSeeDoc.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_SeeDoc, true);
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Tag = event.Source.Tag;
                
                % Create Apply Changes button
                app.SetParamsFigs{idx2}.ButtonApplyChanges = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Position = [350 hh-35 100 20];
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Text = 'Apply Changes';
                app.SetParamsFigs{idx2}.ButtonApplyChanges.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_ApplyChanges,true);
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Tag=event.Source.Tag;
                                
                % Display parameters selection
                for ii=1:length(cc)
                    pos_bott      = hh-(ii+0.5)*app.incr_height;
                    % Info Button
                    app.SetParamsFigs{idx2}.param{ii}.info = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                    app.SetParamsFigs{idx2}.param{ii}.info.Icon = 'infoIcon.png';
                    app.SetParamsFigs{idx2}.param{ii}.info.Tooltip = {cc{ii}.info};
                    app.SetParamsFigs{idx2}.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                    app.SetParamsFigs{idx2}.param{ii}.info.Text = '';
                    typ=cc{ii}.type;
                    % Label
                    app.SetParamsFigs{idx2}.param{ii}.label = uilabel(app.SetParamsFigs{idx2}.fig);
                    app.SetParamsFigs{idx2}.param{ii}.label.HorizontalAlignment = 'left';
                    app.SetParamsFigs{idx2}.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                    app.SetParamsFigs{idx2}.param{ii}.label.Text = [cc{ii}.name,' : '];
                    switch  typ
                        case 'file'
                            app.SetParamsFigs{idx2}.param{ii}.TextArea = uieditfield(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.TextArea.Editable = 'off';
                            if isempty(cc{ii}.val), tt='Select a file (.png,.jpg,.tif,.mat)'; else, tt=['...',cc{ii}.val(end-30:end)]; end
                            app.SetParamsFigs{idx2}.param{ii}.TextArea.Value=tt;
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Text = 'Browse';
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) ButtonPushed_SetParamsFigBrowse(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Tag = event.Source.Tag;
                        case 'vecInt'
                            app.SetParamsFigs{idx2}.param{ii}.openBracket = uilabel(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.Text = '[';
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.FontSize = 30;
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket = uilabel(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.Text = ']';
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.FontSize = 30;
                            app.SetParamsFigs{idx2}.param{ii}.EditField = uieditfield(app.SetParamsFigs{idx2}.fig,'text');
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Value=  cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigTextEdit(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'double'
                            app.SetParamsFigs{idx2}.param{ii}.EditField = uieditfield(app.SetParamsFigs{idx2}.fig,'numeric');
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Value=  str2num(cc{ii}.val);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigNumEdit(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'dropDown'
                            app.SetParamsFigs{idx2}.param{ii}.dropDown = uidropdown(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Items = cc{ii}.items;
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Value = cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigDropDown(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Tag = event.Source.Tag;
                        case 'boolean'
                            app.SetParamsFigs{idx2}.param{ii}.boolean = uiswitch(app.SetParamsFigs{idx2}.fig, 'slider');
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Position = [160 pos_bott 35 15];
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Items = {'0','1'};
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Value = cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.boolean.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigBoolean(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Tag = event.Source.Tag;
                    end         
                end
            else
                % Show figure (already created)
                app.SetParamsFigs{idx2}.fig.Visible='on';
                BringAppFront(app)

                % Update Tags in case a delete or de Up/Down has been pushed               
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Tag = event.Source.Tag;
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Tag=event.Source.Tag;
                for ii=1:length(cc)
                    typ=cc{ii}.type;
                    switch  typ
                        case 'file'
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Tag = event.Source.Tag;
                        case 'vecInt'
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'double'
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'dropDown'
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Tag = event.Source.Tag;
                        case 'boolean'
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Tag = event.Source.Tag; 
                    end
                end
            end

            
            % Disable buttons Up/Down/Delete
            eval(['app.ButtonSelect',tag,'.Enable="off";']);
            app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','off');
            eval(['nobj=length(app.',tag,'Cell);']);
            for ii=1:nobj
                if strcmp(tag,'LinOp')
                    app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','off');
                    app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','off');
                end
                app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','off');
            end
        end

        % Button pushed function: ButtonSelectDF, ButtonSelectLinOp, 
        % ButtonSelectOpti, ButtonSelectRegul
        function ButtonPushed_Select(app, event)
            % Get Tag
            tag=event.Source.Tag;    
            
            % Get length cell
            eval(['idx=length(app.',tag,'Cell)+1;']);    
            
            % Add name info
            idshiftparam=0;
            switch tag
                case 'LinOp'
                    prefix='LinOp';
                    app.LinOpCell{idx}.name=app.DropDownLinOp.Value;                    
                case 'DF'
                    prefix='Cost';
                    for i=1:length(app.dfListGUI)
                        if strcmp(app.DropDownDF.Value, app.dfListGUI{i})
                            app.DFCell{idx}.name = app.dfListCode{i};
                        end
                    end
                case 'Regul'
                    prefix='Regul';
                    app.RegulCell{idx}.name=app.DropDownRegul.Value;
                    app.RegulCell{idx}.parameters{1}.name = 'lambda';
                    app.RegulCell{idx}.parameters{1}.type = 'double';
                    app.RegulCell{idx}.parameters{1}.val = '1e-1';
                    app.RegulCell{idx}.parameters{1}.info = 'Regularization parameter';
                    app.RegulCell{idx}.parameters{1}.default = '-1';
                    idshiftparam=1;
                case 'Opti'
                    app.ButtonRUN.Enable = 'on';
            end       
            
            % Hide 
            eval(['Hide',tag,'(app);']);
            
            if strcmp(tag,'Opti')
                ii=strcmp(cellfun(@(x) x.name,app.listOpti,'UniformOutput',false),app.DropDownOpti.Value);
                app.OptiCell{idx} = app.listOpti{ii};
            else
                % Get parameters from comments
                text=splitlines(fileread([prefix,eval(['app.',tag,'Cell{idx}.name']),'.m']));
                %    Get notation
                splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split');
                eval(['app.',tag,'Cell{idx}.notation=splitxt{2};']);
                %    Get call line
                if strcmp(tag,'Regul')
                    splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcallOpReg'))},'-','split');
                    eval(['app.',tag,'Cell{idx}.callOpReg=splitxt{2};']);
                    splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcallCostReg'))},'-','split');
                    eval(['app.',tag,'Cell{idx}.callCostReg=splitxt{2};']);
                else
                    splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split');
                    eval(['app.',tag,'Cell{idx}.call=splitxt{2};']);
                end
                %    Get parameters
                ll=text(~cellfun(@isempty, regexp(text, 'GUIparam')));
                for ii=1:length(ll)
                    splitxt=regexp(ll{ii},'-','split');
                    if ~strcmp(tag,'DF') || ~strcmp(splitxt{2},'InputSize') % Input Size is known through the data for the DF
                        eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.name=splitxt{2};']);
                        if ~isempty(regexp(splitxt{3},'dropDown','once'))
                            eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.type=''dropDown'';']);
                            tt=regexp(splitxt{3},'/','split');
                            eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.items=tt(2:end);']);
                        else
                            eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.type=splitxt{3};']);
                        end
                        if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
                        eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.val=text;']);
                        eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.default=text;']);
                        eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.info=splitxt{5};']);
                    else
                        idshiftparam = -1;
                    end
                end
            end
                        
            % Show 
            eval(['Show',tag,'(app);']); 
        end

        % Value changed function: CheckBoxPositivity
        function CheckBoxChanged_Positivity(app, event)
            app.isPositivity = app.CheckBoxPositivity.Value;
            ShowRegul(app);
        end

        % Button pushed function: ButtonGenerateScript
        function ButtonPushed_GenerateScript(app, event)
            % Generate script
            warning off
            GenerateAndCheckScript(app);
            warning on
            
            % Write in file      
            [file,path]=uiputfile('.m','Save Script',['GlobalBioIm_',app.EditFieldPipeline.Value,'_Script.m']); % Get the filename and the path to save the pipeline
            BringAppFront(app);
            if file~=0
                fileID = fopen([path,file],'w');
                fprintf(fileID,'%s\n',app.script.header{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.params{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.gpuConverter{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.linop{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.cost{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.algo{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.save{:});fprintf(fileID,'\n');
                fclose(fileID);
            end
        end

        % Button pushed function: ButtonCreatePipeline
        function ButtonPushed_CreatePipeline(app, event)
            InitPipeline(app,'New');
        end

        % Button pushed function: ButtonLoadPipeline
        function ButtonPushed_LoadPipeline(app, event)
            InitPipeline(app,'Browse');
        end

        % Value changed function: CheckBoxDiscardDefaultParams
        function CheckBoxChanged_DiscardDefaultParams(app, event)
            app.discardDefaultParams = app.CheckBoxDiscardDefaultParams.Value;            
        end

        % Value changed function: DropDownUseGPU
        function DropDownChanged_UseGPU(app, event)
            it = app.DropDownUseGPU.Value;
            switch it
                case 'Off'
                    app.useGPU=0;
                case 'On (Matlab Toolbox)'
                    app.useGPU=1;
                case 'On (CudaMat)'
                    app.useGPU=2;
            end
        end

        % Button pushed function: ButtonRUN
        function ButtonPushed_RUN(app, event)
            if isempty(app.savePath)
                warndlg('Please select a file to save results');
            else
                [err,msg]=GenerateAndCheckScript(app);
                if ~err %% No errors
                    % Script is up-to-date after check pipeline, only need to run
                    eval([sprintf('%s\n',app.script.header{:}),...
                        sprintf('%s\n',app.script.params{:}) ,...
                        sprintf('%s\n',app.script.gpuConverter{:}) ,...
                        sprintf('%s\n',app.script.linop{:}) ,...
                        sprintf('%s\n',app.script.cost{:}) ,...
                        sprintf('%s\n',app.script.algo{:}), ...
                        sprintf('%s\n',app.script.save{:})]);
                else
                    OpenLog(app,msg,'red');
                end
            end
        end

        % Value changed function: CheckBoxPreCompute
        function CheckBoxPreComputeValueChanged(app, event)
            app.doPrecompute = app.CheckBoxPreCompute.Value;
            if ~isempty(app.OptiCell)
                tmp=app.OptiCell{1};
                ButtonPushed_RefreshAlgorithmsList(app);
                if ~isempty(app.listOpti)
                    app.OptiCell{1}=tmp;
                    ii=strcmp(cellfun(@(x) x.name,app.listOpti,'UniformOutput',false),tmp.name);
                    app.OptiCell{1}.call = app.listOpti{ii}.call;
                    ShowOpti(app);
                    if ~isempty(app.ParamRecapFig)
                        set( findall(app.GlobalBioImGUIUIFigure, '-property', 'Enable'), 'Enable', 'off');
                        app.ButtonGenerateScript.Enable ='on';
                        app.CheckBoxDiscardDefaultParams.Enable = 'on';app.ButtonInfoDiscardDefaultParams.Enable = 'on';
                        app.CheckBoxPreCompute.Enable ='on';app.ButtonInfoPreCompute.Enable ='on';
                        app.DropDownUseGPU.Enable ='on'; app.ButtonInfoUseGPU.Enable ='on';app.UseGPUDropDownLabel.Enable ='on';
                        app.DropDownDisplay.Enable = 'on'; app.ButtonInfoDisplay.Enable = 'on'; app.DisplayLabel.Enable ='on';
                        app.ButtonBrowseResultFile.Enable ='on'; app.EditFieldResultPath.Enable = 'on'; app.FiletosavetheresultEditFieldLabel.Enable = 'on';
                        app.GENERALOPTIONSLabel.Enable ='on';
                    end
                    app.ButtonRUN.Enable ='on';
                end                
            end
        end

        % Button pushed function: ButtonInfoCostEvolDisp
        function ButtonInfoCostEvolDispPushed(app, event)
            app.dispCostEvol = app.CheckBoxCostEvolDisp.Value; 
        end

        % Value changed function: CheckBoxNormLinOps
        function CheckBoxNormLinOpsValueChanged(app, event)
            app.computeNormLinOp = app.CheckBoxNormLinOps.Value;
        end

        % Button pushed function: ButtonParametersRecap
        function ButtonPushed_ParametersRecap(app, event)
            
            % Get number of objects
            nLinOp = length(app.LinOpCell);
            nDF    = length(app.DFCell);
            nRegul = length(app.RegulCell);
            nOpti  = length(app.OptiCell);
            nObj   = nLinOp+nDF+nRegul+nOpti;
            
            hh=sum(cellfun(@(x) length(x.parameters),app.LinOpCell));
            hh=hh+sum(cellfun(@(x) length(x.parameters),app.DFCell));
            hh=hh+sum(cellfun(@(x) length(x.parameters),app.RegulCell));
            hh=hh+sum(cellfun(@(x) length(x.parameters),app.OptiCell));
            hh=hh+nObj;
            hh=hh*app.incr_height;  
            
            % If RUN allowed, create RUN button
            if strcmp(app.ButtonRUN.Enable,'on')
                isRun=1;
            else
                isRun=0;
            end
            
            % Make all set params windows not visible
            for ii=1:length(app.SetParamsFigs)
                if ~isempty(app.SetParamsFigs{ii})
                    app.SetParamsFigs{ii}.fig.Visible = 'off';
                end
            end
            set( findall(app.GlobalBioImGUIUIFigure, '-property', 'Enable'), 'Enable', 'off');
            if isRun, app.ButtonRUN.Enable ='on';end
            app.ButtonGenerateScript.Enable ='on';
            app.CheckBoxDiscardDefaultParams.Enable = 'on';app.ButtonInfoDiscardDefaultParams.Enable = 'on';
            app.CheckBoxPreCompute.Enable ='on';app.ButtonInfoPreCompute.Enable ='on';
            app.DropDownUseGPU.Enable ='on'; app.ButtonInfoUseGPU.Enable ='on';app.UseGPUDropDownLabel.Enable ='on';
            app.DropDownDisplay.Enable = 'on'; app.ButtonInfoDisplay.Enable = 'on'; app.DisplayLabel.Enable ='on';
            app.ButtonBrowseResultFile.Enable ='on'; app.EditFieldResultPath.Enable = 'on'; app.FiletosavetheresultEditFieldLabel.Enable = 'on';
            app.GENERALOPTIONSLabel.Enable ='on';
            
            
            % Create Figure
            app.ParamRecapFig.fig = uifigure;
            app.ParamRecapFig.fig.Resize = 'off';
            app.ParamRecapFig.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) 0 0 0];
            app.ParamRecapFig.fig.Name  = 'Recap of Selected Parameters';
            app.ParamRecapFig.fig.Scrollable = 'on';
            set(app.ParamRecapFig.fig,'CloseRequestFcn','');  % Disable using the cross to close the window
            
            % Create Apply Changes button
            app.ParamRecapFig.ButtonApplyChanges = uibutton(app.ParamRecapFig.fig,'push');
            app.ParamRecapFig.ButtonApplyChanges.Position = [340 (hh-15) 100 20];
            app.ParamRecapFig.ButtonApplyChanges.Text = 'Apply Changes';
            app.ParamRecapFig.ButtonApplyChanges.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_ApplyChangesRecap,true);


            % Go through all the objects in the cells
            for idx=1:nObj
                % case 'LinOp'
                if idx<=nLinOp
                    idx2 = idx;
                    cc=app.LinOpCell{idx2};
                    tag=['LinOp',num2str(idx2)];
                    % case 'DF'
                elseif idx>nLinOp && idx<=nLinOp+nDF
                    idx2 = idx-nLinOp;
                    cc=app.DFCell{idx2};
                    tag=['DF',num2str(idx2)];
                    idx2=idx2+app.maxLinOp;
                    % case 'Regul'
                elseif idx>nLinOp+nDF && idx<=nLinOp+nDF+nRegul
                    idx2 = idx-nLinOp-nDF;
                    cc=app.RegulCell{idx2};
                    tag=['Regul',num2str(idx2)];
                    idx2=idx2+app.maxLinOp+app.maxDF;
                    % case 'Opti'
                else
                    idx2 = idx-nLinOp-nDF-nRegul;
                    cc=app.OptiCell{idx2};
                    tag=['Opti',num2str(idx2)];
                    idx2=idx2+app.maxLinOp+app.maxDF+app.maxRegul;
                end
                
                % Create Title Name
                app.ParamRecapFig.title{idx} = uilabel(app.ParamRecapFig.fig);
                app.ParamRecapFig.title{idx}.FontSize = 16;
                app.ParamRecapFig.title{idx}.FontWeight = 'bold';
                app.ParamRecapFig.title{idx}.Position = [19 hh-25 300 38];
                app.ParamRecapFig.title{idx}.Text = [tag(1:end-1) ': ' cc.name];
                
                % Add parameters
                ind=1;
                for ii=1:length(cc.parameters)
                    if ~(app.discardDefaultParams && isequal(cc.parameters{ii}.val,cc.parameters{ii}.default))
                        diff_pos_bott      = (ind+0.1)*app.incr_height;
                        % Info Button
                        app.ParamRecapFig.params{idx2}{ii}.info = uibutton(app.ParamRecapFig.fig, 'push');
                        app.ParamRecapFig.params{idx2}{ii}.info.Icon = 'infoIcon.png';
                        app.ParamRecapFig.params{idx2}{ii}.info.Tooltip = {cc.parameters{ii}.info};
                        app.ParamRecapFig.params{idx2}{ii}.info.Position = [30 hh-diff_pos_bott 20 app.def_height];
                        app.ParamRecapFig.params{idx2}{ii}.info.Text = '';
                        typ=cc.parameters{ii}.type;
                        % Label
                        app.ParamRecapFig.params{idx2}{ii}.label = uilabel(app.ParamRecapFig.fig);
                        app.ParamRecapFig.params{idx2}{ii}.label.HorizontalAlignment = 'left';
                        app.ParamRecapFig.params{idx2}{ii}.label.Position = [60 hh-diff_pos_bott 80 app.def_height];
                        app.ParamRecapFig.params{idx2}{ii}.label.Text = [cc.parameters{ii}.name,' : '];
                        switch  typ
                            case 'file'
                                app.ParamRecapFig.params{idx2}{ii}.TextArea = uieditfield(app.ParamRecapFig.fig);
                                app.ParamRecapFig.params{idx2}{ii}.TextArea.Position = [150 hh-diff_pos_bott 220 app.def_height];
                                app.ParamRecapFig.params{idx2}{ii}.TextArea.Editable = 'off';
                                if isempty(cc.parameters{ii}.val), tt='Select a file (.png,.jpg,.tif,.mat)'; else, tt=['...',cc.parameters{ii}.val(end-30:end)]; end
                                app.ParamRecapFig.params{idx2}{ii}.TextArea.Value=tt;
                                app.ParamRecapFig.params{idx2}{ii}.butBrowse = uibutton(app.ParamRecapFig.fig, 'push');
                                app.ParamRecapFig.params{idx2}{ii}.butBrowse.Text = 'Browse';
                                app.ParamRecapFig.params{idx2}{ii}.butBrowse.Position = [380 hh-diff_pos_bott 60 app.def_height];
                                app.ParamRecapFig.params{idx2}{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) ButtonPushed_SetParamsFigBrowse(app,event,ii), true);
                                app.ParamRecapFig.params{idx2}{ii}.butBrowse.Tag = tag;
                            case 'vecInt'
                                app.ParamRecapFig.params{idx2}{ii}.openBracket = uilabel(app.ParamRecapFig.fig);
                                app.ParamRecapFig.params{idx2}{ii}.openBracket.Position = [140 hh-diff_pos_bott-5 10 app.def_height*1.8];
                                app.ParamRecapFig.params{idx2}{ii}.openBracket.Text = '[';
                                app.ParamRecapFig.params{idx2}{ii}.openBracket.FontSize = 30;
                                app.ParamRecapFig.params{idx2}{ii}.closeBracket = uilabel(app.ParamRecapFig.fig);
                                app.ParamRecapFig.params{idx2}{ii}.closeBracket.Position = [232 hh-diff_pos_bott-5 10 app.def_height*1.8];
                                app.ParamRecapFig.params{idx2}{ii}.closeBracket.Text = ']';
                                app.ParamRecapFig.params{idx2}{ii}.closeBracket.FontSize = 30;
                                app.ParamRecapFig.params{idx2}{ii}.EditField = uieditfield(app.ParamRecapFig.fig,'text');
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Position = [150 hh-diff_pos_bott 80 app.def_height];
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Value=  cc.parameters{ii}.val;
                                app.ParamRecapFig.params{idx2}{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigTextEdit(app,event,ii), true);
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Tag = tag;
                            case 'double'
                                app.ParamRecapFig.params{idx2}{ii}.EditField = uieditfield(app.ParamRecapFig.fig,'numeric');
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Position = [150 hh-diff_pos_bott 80 app.def_height];
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Value=  str2num(cc.parameters{ii}.val);
                                app.ParamRecapFig.params{idx2}{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigNumEdit(app,event,ii), true);
                                app.ParamRecapFig.params{idx2}{ii}.EditField.Tag = tag;
                            case 'dropDown'
                                app.ParamRecapFig.params{idx2}{ii}.dropDown = uidropdown(app.ParamRecapFig.fig);
                                app.ParamRecapFig.params{idx2}{ii}.dropDown.Position = [150 hh-diff_pos_bott 80 app.def_height];
                                app.ParamRecapFig.params{idx2}{ii}.dropDown.Items = cc.parameters{ii}.items;
                                app.ParamRecapFig.params{idx2}{ii}.dropDown.Value = cc.parameters{ii}.val;
                                app.ParamRecapFig.params{idx2}{ii}.dropDown.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigDropDown(app,event,ii), true);
                                app.ParamRecapFig.params{idx2}{ii}.dropDown.Tag = tag;
                            case 'boolean'
                                app.ParamRecapFig.params{idx2}{ii}.boolean = uiswitch(app.ParamRecapFig.fig, 'slider');
                                app.ParamRecapFig.params{idx2}{ii}.boolean.Position = [160 hh-diff_pos_bott 35 15];
                                app.ParamRecapFig.params{idx2}{ii}.boolean.Items = {'0','1'};
                                app.ParamRecapFig.params{idx2}{ii}.boolean.Value = cc.parameters{ii}.val;
                                app.ParamRecapFig.params{idx2}{ii}.boolean.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigBoolean(app,event,ii), true);
                                app.ParamRecapFig.params{idx2}{ii}.boolean.Tag = tag;
                        end
                        ind=ind+1;
                    end
                end
                % update display option
                hh = hh-(ind-0.3)*app.incr_height;
            end
            
        end

        % Button pushed function: ButtonRefreshAlgorithmsList
        function ButtonPushed_RefreshAlgorithmsList(app, event)
            app.OptiCell={};
            if ~isempty(app.SetParamsFigs{app.maxLinOp+app.maxDF+app.maxRegul+1})
                app.SetParamsFigs{app.maxLinOp+app.maxDF+app.maxRegul+1}.fig.delete;
                app.SetParamsFigs{app.maxLinOp+app.maxDF+app.maxRegul+1}=[];
            end
            app.ButtonSelectOpti.Enable = 'on'; app.ButtonRUN.Enable = 'off';
            HideOpti(app);
            if isempty(app.DFCell) && isempty(app.RegulCell)
                app.DropDownOpti.Items = {''};
                app.DropDownOpti.Enable='off';app.ButtonSeeDocOpti.Enable ='off';app.ButtonSelectOpti.Enable='off';app.ButtonInfoOpti.Enable ='off';app.ButtonCheckPipelineAlgoPanel.Enable ='off';app.AlgorithmLabel.Enable = 'off';
                app.NameOpti1.FontColor = 'red';app.NameOpti1.Visible = 'on';
                app.NameOpti1.Text ='A nonempty cost function is required';
            else
                % Generate script
                [err,~]=GenerateAndCheckScript(app);
                if err
                    app.DropDownOpti.Enable='off';app.ButtonSeeDocOpti.Enable ='off';app.ButtonSelectOpti.Enable='off';app.ButtonInfoOpti.Enable ='off';app.ButtonCheckPipelineAlgoPanel.Enable ='on';app.AlgorithmLabel.Enable = 'off';
                    app.NameOpti1.FontColor = 'red';HideOpti(app); app.NameOpti1.Visible = 'on';
                    app.listOpti={}; app.OptiListCode={};
                    app.NameOpti1.Text ='A valid cost function is required (Check the pipeline)';
                else
                    app.DropDownOpti.Enable='on';app.ButtonSeeDocOpti.Enable ='on';app.ButtonInfoOpti.Enable ='on';app.ButtonCheckPipelineAlgoPanel.Enable ='on';app.AlgorithmLabel.Enable = 'on';
                    app.NameOpti1.FontColor = 'black';
                    % Evaluate Script
                    eval([sprintf('%s\n',app.script.header{:}),...
                        sprintf('%s\n',app.script.params{:}) ,...
                        sprintf('%s\n',app.script.gpuConverter{:}) ,...
                        sprintf('%s\n',app.script.linop{:}) ,...
                        sprintf('%s\n',app.script.cost{:})]);
                    % Get Cost and Operators
                    idx=1;
                    for ii=1:length(app.DFCell)
                        Costs{idx}=DF;
                        NameCosts{idx}='DF';
                        Ops{idx}={};
                        NamesOps{idx}={};
                        for ii=1:length(app.LinOpCell)
                            NamesOps{idx}=[NamesOps{idx},['H',num2str(ii)]];
                            Ops{idx}=[Ops{idx},{eval(['H',num2str(ii)])}];
                        end
                        idx=idx+1;
                    end
                    for ii=1:length(app.RegulCell)
                        Costs{idx}=eval(['CostReg',num2str(ii)]);
                        NameCosts{idx}=['CostReg',num2str(ii)];
                        Ops{idx}={eval(['OpReg',num2str(ii)])};
                        NamesOps{idx}={['OpReg',num2str(ii)]};
                        idx=idx+1;
                    end
                    % Get list of Opti
                    [app.listOpti, app.OptiListCode ] = GetOptiList(Costs,Ops,NameCosts,NamesOps,app.isPositivity,app.doPrecompute);
                    app.DropDownOpti.Items = cellfun(@(x) x.name,app.listOpti,'UniformOutput',false);
                    if isempty(app.listOpti)
                        app.NameOpti1.Text ='No algorithm is available to minimize the selected cost';
                        app.NameOpti1.FontColor = 'red';app.NameOpti1.Visible = 'on'; app.ButtonRUN.Enable = 'off';
                    end
                end
            end
        end

        % Button pushed function: ButtonBrowseResultFile
        function ButtonPushed_BrowseResultFile(app, event)
            if ~isempty(app.savePath)
                [file,path]=uiputfile('*.*','Select a file to save results',app.savePath);
            else
                [file,path]=uiputfile('*.*','Select a file to save results');
            end
            BringAppFront(app);
            if file~=0
                app.savePath = [path,file]; app.EditFieldResultPath.Value = ['...',app.savePath(max(1,length(app.savePath)-30):end)];
            end
        end

        % Close request function: GlobalBioImGUIUIFigure
        function GlobalBioImGUIUIFigureCloseRequest(app, event)
            for ii=1:length(app.SetParamsFigs)                
                if ~isempty(app.SetParamsFigs{ii})
                    app.SetParamsFigs{ii}.fig.delete;
                end
            end
            if ~isempty(app.ParamRecapFig) && ishandle(app.ParamRecapFig.fig)
                app.ParamRecapFig.fig.delete;
            end
            delete(app)
        end

        % Value changed function: DropDownDisplay
        function DropDownChanged_Display(app, event)
            app.display = app.DropDownDisplay.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.GlobalBioImGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @GlobalBioImGUIUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.LabelGlobalBioIm = uilabel(app.Main);
            app.LabelGlobalBioIm.Text = 'GlobalBioIm';
            app.ButtonSelectPipeline = uibutton(app.Main, 'push');
            app.ButtonSelectPipeline.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SelectPipeline, true);
            app.ButtonSelectPipeline.Text = 'Select';
            app.DropDownPipeline = uidropdown(app.Main);
            app.NameLabel = uilabel(app.Main);
            app.NameLabel.Enable = 'off';
            app.NameLabel.Text = 'Name';
            app.EditFieldPipeline = uieditfield(app.Main, 'text');
            app.EditFieldPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_NamePipeline, true);
            app.EditFieldPipeline.Enable = 'off';
            app.EditFieldPipeline.Value = 'Name of pipeline';
            app.DescriptionLabel = uilabel(app.Main);
            app.DescriptionLabel.Enable = 'off';
            app.DescriptionLabel.Text = 'Description';
            app.TextAreaDescriptionPipeline = uitextarea(app.Main);
            app.TextAreaDescriptionPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_DescriptionPipeline, true);
            app.TextAreaDescriptionPipeline.Enable = 'off';
            app.TextAreaDescriptionPipeline.Value = {'Description of Pipeline'};
            app.LabelCopyright = uilabel(app.Main);
            app.LabelCopyright.Text = 'Copyright (C) 2019 - The GlobalBioIm Team';
            app.ReferencesPleaseLabel = uilabel(app.Main);
            app.ReferencesPleaseLabel.Text = {'This program is distributed under the terms of the GNU General Public License.'; 'Each created pipeline relies on several works and relevant references can be '; 'found in the online documentation of GlobalBioIm (see the doc of the LinOp '; 'Costs and Opti used in the pipeline). Please make sure to properly cite to them '; 'whenever you present or publish results that use GlobalBioIm.'};
            app.ButtonCreatePipeline = uibutton(app.Main, 'push');
            app.ButtonCreatePipeline.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CreatePipeline, true);
            app.ButtonCreatePipeline.Text = 'Create Pipeline';
            app.ButtonLoadPipeline = uibutton(app.Main, 'push');
            app.ButtonLoadPipeline.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_LoadPipeline, true);
            app.ButtonLoadPipeline.Text = 'Load Pipeline';
            app.LabelSelectaDefaultPipeline = uilabel(app.Main);
            app.LabelSelectaDefaultPipeline.Text = 'Select a Default Pipeline:';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextAreaForwardModel = uitextarea(app.ForwardModel);
            app.TextAreaForwardModel.Editable = 'off';
            app.TextAreaForwardModel.Enable = 'off';
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Enable = 'off';
            app.LabelTextArea.Text = 'Forward Model';
            app.ButtonSelectLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSelectLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectLinOp.Enable = 'off';
            app.ButtonSelectLinOp.Text = 'Select';
            app.ButtonSeeDocLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSeeDocLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocLinOp.Enable = 'off';
            app.ButtonSeeDocLinOp.Text = 'Doc.';
            app.OperatorLabel = uilabel(app.ForwardModel);
            app.OperatorLabel.Enable = 'off';
            app.OperatorLabel.Text = 'Operator';
            app.DropDownLinOp = uidropdown(app.ForwardModel);
            app.DropDownLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownLinOp.Enable = 'off';
            app.DropDownLinOp.Value = 'Test';
            app.PanelHierarchyLinOp = uipanel(app.ForwardModel);
            app.PanelHierarchyLinOp.Title = 'Hierarchy of Selected Operators';
            app.NotationLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp1.Text = 'H1 :';
            app.NameLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp1.Text = 'LinOp1';
            app.ButtonSetParamsLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp1.Text = 'Set Parameters';
            app.ButtonUpLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp1.Icon = 'upArrow.png';
            app.ButtonUpLinOp1.Text = '';
            app.ButtonDownLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp1.Icon = 'downArrow.png';
            app.ButtonDownLinOp1.Text = '';
            app.ButtonDeleteLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp1.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp1.Text = '';
            app.NotationLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp2.Text = 'H2 :';
            app.NameLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp2.Text = 'LinOp2';
            app.ButtonSetParamsLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp2.Text = 'Set Parameters';
            app.ButtonUpLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp2.Icon = 'upArrow.png';
            app.ButtonUpLinOp2.Text = '';
            app.ButtonDownLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp2.Icon = 'downArrow.png';
            app.ButtonDownLinOp2.Text = '';
            app.ButtonDeleteLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp2.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp2.Text = '';
            app.NotationLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp3.Text = 'H3 :';
            app.NameLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp3.Text = 'LinOp3';
            app.ButtonSetParamsLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp3.Text = 'Set Parameters';
            app.ButtonUpLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp3.Icon = 'upArrow.png';
            app.ButtonUpLinOp3.Text = '';
            app.ButtonDownLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp3.Icon = 'downArrow.png';
            app.ButtonDownLinOp3.Text = '';
            app.ButtonDeleteLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp3.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp3.Text = '';
            app.NotationLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp4.Text = 'H4 :';
            app.NameLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp4.Text = 'LinOp4';
            app.ButtonSetParamsLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp4.Text = 'Set Parameters';
            app.ButtonUpLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp4.Icon = 'upArrow.png';
            app.ButtonUpLinOp4.Text = '';
            app.ButtonDownLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp4.Icon = 'downArrow.png';
            app.ButtonDownLinOp4.Text = '';
            app.ButtonDeleteLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp4.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp4.Text = '';
            app.NotationLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp5.Text = 'H5 :';
            app.NameLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp5.Text = 'LinOp5';
            app.ButtonSetParamsLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp5.Text = 'Set Parameters';
            app.ButtonUpLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp5.Icon = 'upArrow.png';
            app.ButtonUpLinOp5.Text = '';
            app.ButtonDownLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp5.Icon = 'downArrow.png';
            app.ButtonDownLinOp5.Text = '';
            app.ButtonDeleteLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp5.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp5.Text = '';
            app.NotationLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp6.Text = 'H6 :';
            app.NameLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp6.Text = 'LinOp6';
            app.ButtonSetParamsLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp6.Text = 'Set Parameters';
            app.ButtonUpLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp6.Icon = 'upArrow.png';
            app.ButtonUpLinOp6.Text = '';
            app.ButtonDownLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp6.Icon = 'downArrow.png';
            app.ButtonDownLinOp6.Text = '';
            app.ButtonDeleteLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp6.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp6.Text = '';
            app.ButtonInfoLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonInfoLinOp.Icon = 'infoIcon.png';
            app.ButtonInfoLinOp.Enable = 'off';
            app.ButtonInfoLinOp.Text = '';
            app.ButtonCheckPipelineFwdPanel = uibutton(app.ForwardModel, 'push');
            app.ButtonCheckPipelineFwdPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckPipeline, true);
            app.ButtonCheckPipelineFwdPanel.Enable = 'off';
            app.ButtonCheckPipelineFwdPanel.Text = 'Check Pipeline';
            app.ButtonSaveFwdPanel = uibutton(app.ForwardModel, 'push');
            app.ButtonSaveFwdPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveFwdPanel.Enable = 'off';
            app.ButtonSaveFwdPanel.Text = 'Save Pipeline';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.TextAreaCost = uitextarea(app.CostFunction);
            app.TextAreaCost.Editable = 'off';
            app.TextAreaCost.Enable = 'off';
            app.CostFunctionLabel = uilabel(app.CostFunction);
            app.CostFunctionLabel.Enable = 'off';
            app.CostFunctionLabel.Text = 'Cost Function';
            app.ButtonSelectDF = uibutton(app.CostFunction, 'push');
            app.ButtonSelectDF.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectDF.Enable = 'off';
            app.ButtonSelectDF.Text = 'Select';
            app.ButtonSeeDocDF = uibutton(app.CostFunction, 'push');
            app.ButtonSeeDocDF.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocDF.Enable = 'off';
            app.ButtonSeeDocDF.Text = 'Doc.';
            app.DatafidelityLabel = uilabel(app.CostFunction);
            app.DatafidelityLabel.Enable = 'off';
            app.DatafidelityLabel.Text = 'Data-fidelity';
            app.DropDownDF = uidropdown(app.CostFunction);
            app.DropDownDF.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownDF.Enable = 'off';
            app.DropDownDF.Value = 'Test';
            app.FMPanel_CostFunction = uipanel(app.CostFunction);
            app.FMPanel_CostFunction.Title = 'Selected Regularization Terms';
            app.NotationRegul1 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul1.Text = 'R1 :';
            app.NameRegul1 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul1.Text = 'Regul1';
            app.ButtonSetParamsRegul1 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul1.Text = 'Set Parameters';
            app.ButtonDeleteRegul1 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul1.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul1.Text = '';
            app.NotationRegul2 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul2.Text = 'R2 :';
            app.NameRegul2 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul2.Text = 'Regul2';
            app.ButtonSetParamsRegul2 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul2.Text = 'Set Parameters';
            app.ButtonDeleteRegul2 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul2.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul2.Text = '';
            app.NotationRegul3 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul3.Text = 'R3 :';
            app.NameRegul3 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul3.Text = 'Regul3';
            app.ButtonSetParamsRegul3 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul3.Text = 'Set Parameters';
            app.ButtonDeleteRegul3 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul3.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul3.Text = '';
            app.ButtonSelectRegul = uibutton(app.CostFunction, 'push');
            app.ButtonSelectRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectRegul.Enable = 'off';
            app.ButtonSelectRegul.Text = 'Select';
            app.ButtonSeeDocRegul = uibutton(app.CostFunction, 'push');
            app.ButtonSeeDocRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocRegul.Enable = 'off';
            app.ButtonSeeDocRegul.Text = 'Doc.';
            app.RegularizerLabel = uilabel(app.CostFunction);
            app.RegularizerLabel.Enable = 'off';
            app.RegularizerLabel.Text = 'Regularizer';
            app.DropDownRegul = uidropdown(app.CostFunction);
            app.DropDownRegul.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownRegul.Enable = 'off';
            app.DropDownRegul.Value = 'Test';
            app.CheckBoxPositivity = uicheckbox(app.CostFunction);
            app.CheckBoxPositivity.ValueChangedFcn = createCallbackFcn(app, @CheckBoxChanged_Positivity, true);
            app.CheckBoxPositivity.Enable = 'off';
            app.CheckBoxPositivity.Text = ' Add Positivity Constraint';
            app.FMPanel_CostFunction_2 = uipanel(app.CostFunction);
            app.FMPanel_CostFunction_2.Title = 'Selected Data-Fidelity Term';
            app.NotationDF1 = uilabel(app.FMPanel_CostFunction_2);
            app.NotationDF1.Text = 'DF :';
            app.NameDF1 = uilabel(app.FMPanel_CostFunction_2);
            app.NameDF1.Text = 'DF1';
            app.ButtonSetParamsDF1 = uibutton(app.FMPanel_CostFunction_2, 'push');
            app.ButtonSetParamsDF1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsDF1.Text = 'Set Parameters';
            app.ButtonDeleteDF1 = uibutton(app.FMPanel_CostFunction_2, 'push');
            app.ButtonDeleteDF1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteDF1.Icon = 'trashIcon.png';
            app.ButtonDeleteDF1.Text = '';
            app.ButtonInfoDF = uibutton(app.CostFunction, 'push');
            app.ButtonInfoDF.Icon = 'infoIcon.png';
            app.ButtonInfoDF.Enable = 'off';
            app.ButtonInfoDF.Text = '';
            app.ButtonInfoRegul = uibutton(app.CostFunction, 'push');
            app.ButtonInfoRegul.Icon = 'infoIcon.png';
            app.ButtonInfoRegul.Enable = 'off';
            app.ButtonInfoRegul.Text = '';
            app.ButtonSaveCostPanel = uibutton(app.CostFunction, 'push');
            app.ButtonSaveCostPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveCostPanel.Enable = 'off';
            app.ButtonSaveCostPanel.Text = 'Save Pipeline';
            app.ButtonCheckPipelineCostPanel = uibutton(app.CostFunction, 'push');
            app.ButtonCheckPipelineCostPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckPipeline, true);
            app.ButtonCheckPipelineCostPanel.Enable = 'off';
            app.ButtonCheckPipelineCostPanel.Text = 'Check Pipeline';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.FMPanel_Optimization = uipanel(app.Optimization);
            app.FMPanel_Optimization.Title = 'Selected Algorithm';
            app.NameOpti1 = uilabel(app.FMPanel_Optimization);
            app.NameOpti1.Text = 'ADMM';
            app.ButtonSetParamsOpti1 = uibutton(app.FMPanel_Optimization, 'push');
            app.ButtonSetParamsOpti1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsOpti1.Text = 'Set Parameters';
            app.ButtonDeleteOpti1 = uibutton(app.FMPanel_Optimization, 'push');
            app.ButtonDeleteOpti1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteOpti1.Icon = 'trashIcon.png';
            app.ButtonDeleteOpti1.Text = '';
            app.ButtonSelectOpti = uibutton(app.Optimization, 'push');
            app.ButtonSelectOpti.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectOpti.Enable = 'off';
            app.ButtonSelectOpti.Text = 'Select';
            app.ButtonSeeDocOpti = uibutton(app.Optimization, 'push');
            app.ButtonSeeDocOpti.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocOpti.Enable = 'off';
            app.ButtonSeeDocOpti.Text = 'Doc.';
            app.AlgorithmLabel = uilabel(app.Optimization);
            app.AlgorithmLabel.Enable = 'off';
            app.AlgorithmLabel.Text = 'Algorithm';
            app.DropDownOpti = uidropdown(app.Optimization);
            app.DropDownOpti.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownOpti.Enable = 'off';
            app.DropDownOpti.Value = 'Test';
            app.ButtonInfoOpti = uibutton(app.Optimization, 'push');
            app.ButtonInfoOpti.Icon = 'infoIcon.png';
            app.ButtonInfoOpti.Enable = 'off';
            app.ButtonInfoOpti.Tooltip = {'The button "Refresh Algorithm List" computes the list of algorithms in GlobalBioIm that can be used to minimize the selected cost function. '; 'Note that for some algorithms (e.g. ADMM Primal-Dual-Condat) there exists a variety of possible instanciation which depend for instance'; ' on the splitting strategy. For such algorithms this software proposes a couple of instanciations that may not be the optimal ones. However'; ' the software tries as much as possible to propose instantiations that do not require nested iterates.'};
            app.ButtonInfoOpti.Text = '';
            app.ButtonRUN = uibutton(app.Optimization, 'push');
            app.ButtonRUN.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_RUN, true);
            app.ButtonRUN.Enable = 'off';
            app.ButtonRUN.Text = 'RUN';
            app.ButtonGenerateScript = uibutton(app.Optimization, 'push');
            app.ButtonGenerateScript.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_GenerateScript, true);
            app.ButtonGenerateScript.Enable = 'off';
            app.ButtonGenerateScript.Text = 'Generate Matlab Script';
            app.ButtonParametersRecap = uibutton(app.Optimization, 'push');
            app.ButtonParametersRecap.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_ParametersRecap, true);
            app.ButtonParametersRecap.Enable = 'off';
            app.ButtonParametersRecap.Text = 'Recap of Selected Parameters';
            app.CheckBoxCostEvolDisp = uicheckbox(app.Optimization);
            app.CheckBoxCostEvolDisp.Enable = 'off';
            app.CheckBoxCostEvolDisp.Visible = 'off';
            app.CheckBoxCostEvolDisp.Text = ' Display the cost-function evolution';
            app.CheckBoxCostEvolDisp.Value = true;
            app.ButtonSaveOptiPanel = uibutton(app.Optimization, 'push');
            app.ButtonSaveOptiPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveOptiPanel.Enable = 'off';
            app.ButtonSaveOptiPanel.Text = 'Save Pipeline';
            app.ButtonCheckPipelineAlgoPanel = uibutton(app.Optimization, 'push');
            app.ButtonCheckPipelineAlgoPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckPipeline, true);
            app.ButtonCheckPipelineAlgoPanel.Enable = 'off';
            app.ButtonCheckPipelineAlgoPanel.Text = 'Check Pipeline';
            app.GENERALOPTIONSLabel = uilabel(app.Optimization);
            app.GENERALOPTIONSLabel.Text = 'GENERAL OPTIONS';
            app.ButtonInfoUseGPU = uibutton(app.Optimization, 'push');
            app.ButtonInfoUseGPU.Icon = 'infoIcon.png';
            app.ButtonInfoUseGPU.Enable = 'off';
            app.ButtonInfoUseGPU.Tooltip = {'Option to make the script run on GPU. Note that the corresponding libraries (here CudaMat or Matlab parallel toolbox) must be properly installed and configurated)'};
            app.ButtonInfoUseGPU.Text = '';
            app.ButtonInfoCostEvolDisp = uibutton(app.Optimization, 'push');
            app.ButtonInfoCostEvolDisp.ButtonPushedFcn = createCallbackFcn(app, @ButtonInfoCostEvolDispPushed, true);
            app.ButtonInfoCostEvolDisp.Icon = 'infoIcon.png';
            app.ButtonInfoCostEvolDisp.Enable = 'off';
            app.ButtonInfoCostEvolDisp.Visible = 'off';
            app.ButtonInfoCostEvolDisp.Text = '';
            app.CheckBoxDiscardDefaultParams = uicheckbox(app.Optimization);
            app.CheckBoxDiscardDefaultParams.ValueChangedFcn = createCallbackFcn(app, @CheckBoxChanged_DiscardDefaultParams, true);
            app.CheckBoxDiscardDefaultParams.Enable = 'off';
            app.CheckBoxDiscardDefaultParams.Text = 'Discard default parameters in script';
            app.CheckBoxDiscardDefaultParams.Value = true;
            app.ButtonInfoDiscardDefaultParams = uibutton(app.Optimization, 'push');
            app.ButtonInfoDiscardDefaultParams.Icon = 'infoIcon.png';
            app.ButtonInfoDiscardDefaultParams.Enable = 'off';
            app.ButtonInfoDiscardDefaultParams.Tooltip = {'This option allows to remove in the generated script (and the recap parameter window) the definition of parameters that have been let to the default value by the user.'};
            app.ButtonInfoDiscardDefaultParams.Text = '';
            app.CheckBoxNormLinOps = uicheckbox(app.Optimization);
            app.CheckBoxNormLinOps.ValueChangedFcn = createCallbackFcn(app, @CheckBoxNormLinOpsValueChanged, true);
            app.CheckBoxNormLinOps.Enable = 'off';
            app.CheckBoxNormLinOps.Visible = 'off';
            app.CheckBoxNormLinOps.Text = 'Compute norm of LinOps';
            app.ButtonInfoNormLinOps = uibutton(app.Optimization, 'push');
            app.ButtonInfoNormLinOps.Icon = 'infoIcon.png';
            app.ButtonInfoNormLinOps.Enable = 'off';
            app.ButtonInfoNormLinOps.Visible = 'off';
            app.ButtonInfoNormLinOps.Tooltip = {'This option will be made available in a future release.'};
            app.ButtonInfoNormLinOps.Text = '';
            app.CheckBoxPreCompute = uicheckbox(app.Optimization);
            app.CheckBoxPreCompute.ValueChangedFcn = createCallbackFcn(app, @CheckBoxPreComputeValueChanged, true);
            app.CheckBoxPreCompute.Enable = 'off';
            app.CheckBoxPreCompute.Tooltip = {'Can speed up calculations at a higher memory cost'};
            app.CheckBoxPreCompute.Text = 'Perform pre-computations';
            app.ButtonInfoPreCompute = uibutton(app.Optimization, 'push');
            app.ButtonInfoPreCompute.Icon = 'infoIcon.png';
            app.ButtonInfoPreCompute.Enable = 'off';
            app.ButtonInfoPreCompute.Tooltip = {'Can speed up calculations at a higher memory cost'};
            app.ButtonInfoPreCompute.Text = '';
            app.ButtonBrowseResultFile = uibutton(app.Optimization, 'push');
            app.ButtonBrowseResultFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_BrowseResultFile, true);
            app.ButtonBrowseResultFile.Enable = 'off';
            app.ButtonBrowseResultFile.Text = 'Browse';
            app.UseGPUDropDownLabel = uilabel(app.Optimization);
            app.UseGPUDropDownLabel.Enable = 'off';
            app.UseGPUDropDownLabel.Text = 'UseGPU';
            app.DropDownUseGPU = uidropdown(app.Optimization);
            app.DropDownUseGPU.Items = {'Off', 'On (Matlab Toolbox)', 'On (CudaMat)'};
            app.DropDownUseGPU.ValueChangedFcn = createCallbackFcn(app, @DropDownChanged_UseGPU, true);
            app.DropDownUseGPU.Enable = 'off';
            app.DropDownUseGPU.Value = 'Off';
            app.Label = uilabel(app.Optimization);
            app.Label.Text = '__________________________________________________________';
            app.Label_2 = uilabel(app.Optimization);
            app.Label_2.Text = '__________________________________________________________';
            app.ButtonRefreshAlgorithmsList = uibutton(app.Optimization, 'push');
            app.ButtonRefreshAlgorithmsList.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_RefreshAlgorithmsList, true);
            app.ButtonRefreshAlgorithmsList.Enable = 'off';
            app.ButtonRefreshAlgorithmsList.Text = 'Refresh Algorithms List';
            app.FiletosavetheresultEditFieldLabel = uilabel(app.Optimization);
            app.FiletosavetheresultEditFieldLabel.Enable = 'off';
            app.FiletosavetheresultEditFieldLabel.Text = 'File to save the result';
            app.EditFieldResultPath = uieditfield(app.Optimization, 'text');
            app.EditFieldResultPath.Editable = 'off';
            app.EditFieldResultPath.Enable = 'off';
            app.EditFieldResultPath.Value = 'Select a file';
            app.ButtonInfoDisplay = uibutton(app.Optimization, 'push');
            app.ButtonInfoDisplay.Icon = 'infoIcon.png';
            app.ButtonInfoDisplay.Enable = 'off';
            app.ButtonInfoDisplay.Tooltip = {'Select how to display the result.'};
            app.ButtonInfoDisplay.Text = '';
            app.DisplayLabel = uilabel(app.Optimization);
            app.DisplayLabel.Enable = 'off';
            app.DisplayLabel.Text = 'Display';
            app.DropDownDisplay = uidropdown(app.Optimization);
            app.DropDownDisplay.Items = {'none', '2D Grayscale', '2D Color', '3D Orthoviews'};
            app.DropDownDisplay.ValueChangedFcn = createCallbackFcn(app, @DropDownChanged_Display, true);
            app.DropDownDisplay.Enable = 'off';
            app.DropDownDisplay.Value = 'none';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end